classdef TRIMLAT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SingleTrimTab                  matlab.ui.container.Tab
        SaveTrimStateButton            matlab.ui.control.Button
        LoadTrimModelButton            matlab.ui.control.Button
        LoadFCButton                   matlab.ui.control.Button
        SuccessfulTrimLamp             matlab.ui.control.Lamp
        SuccessfulTrimLabel            matlab.ui.control.Label
        TrimVehicleButton              matlab.ui.control.Button
        OutputsTabGroup                matlab.ui.container.TabGroup
        TrimOutputsTab                 matlab.ui.container.Tab
        EnvironmentPanel               matlab.ui.container.Panel
        rhoOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_73               matlab.ui.control.Label
        SoSOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_72               matlab.ui.control.Label
        PairOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_70               matlab.ui.control.Label
        tempOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_69               matlab.ui.control.Label
        MassPropertiesPanel            matlab.ui.container.Panel
        massOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_74               matlab.ui.control.Label
        iyzOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_68               matlab.ui.control.Label
        ixyOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_67               matlab.ui.control.Label
        ixzOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_66               matlab.ui.control.Label
        izzOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_65               matlab.ui.control.Label
        iyyOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_64               matlab.ui.control.Label
        ixxOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_63               matlab.ui.control.Label
        zcgOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_62               matlab.ui.control.Label
        ycgOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_61               matlab.ui.control.Label
        xcgOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_60               matlab.ui.control.Label
        weightOutEditField             matlab.ui.control.NumericEditField
        AlphadegLabel_58               matlab.ui.control.Label
        TotalForcesandMomentsPanel     matlab.ui.container.Panel
        NtotOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_57               matlab.ui.control.Label
        MtotOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_56               matlab.ui.control.Label
        LtotOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_55               matlab.ui.control.Label
        ZtotOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_54               matlab.ui.control.Label
        YtotOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_53               matlab.ui.control.Label
        XtotOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_52               matlab.ui.control.Label
        ControlSurfacesPanel           matlab.ui.container.Panel
        dlefOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_51               matlab.ui.control.Label
        drOutEditField                 matlab.ui.control.NumericEditField
        AlphadegLabel_50               matlab.ui.control.Label
        deOutEditField                 matlab.ui.control.NumericEditField
        AlphadegLabel_49               matlab.ui.control.Label
        daOutEditField                 matlab.ui.control.NumericEditField
        AlphadegLabel_48               matlab.ui.control.Label
        PropulsionPanel                matlab.ui.container.Panel
        WdotOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_47               matlab.ui.control.Label
        ThrustOutEditField             matlab.ui.control.NumericEditField
        AlphadegLabel_46               matlab.ui.control.Label
        NpropOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_45               matlab.ui.control.Label
        MpropOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_44               matlab.ui.control.Label
        LpropOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_43               matlab.ui.control.Label
        ZpropOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_42               matlab.ui.control.Label
        YpropOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_41               matlab.ui.control.Label
        XpropOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_40               matlab.ui.control.Label
        AerodynamicsPanel              matlab.ui.container.Panel
        LoDOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_36               matlab.ui.control.Label
        DragOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_35               matlab.ui.control.Label
        LiftOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_34               matlab.ui.control.Label
        NaeroOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_33               matlab.ui.control.Label
        MaeroOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_32               matlab.ui.control.Label
        LaeroOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_31               matlab.ui.control.Label
        ZaeroOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_30               matlab.ui.control.Label
        YaeroOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_29               matlab.ui.control.Label
        XaeroOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_28               matlab.ui.control.Label
        VehicleBodyAccelerationsPanel  matlab.ui.container.Panel
        NzOutEditField                 matlab.ui.control.NumericEditField
        AlphadegLabel_27               matlab.ui.control.Label
        NyOutEditField                 matlab.ui.control.NumericEditField
        AlphadegLabel_26               matlab.ui.control.Label
        NxOutEditField                 matlab.ui.control.NumericEditField
        AlphadegLabel_25               matlab.ui.control.Label
        rbdotOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_24               matlab.ui.control.Label
        qbdotOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_23               matlab.ui.control.Label
        pbdotOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_22               matlab.ui.control.Label
        wbdotOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_21               matlab.ui.control.Label
        vbdotOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_20               matlab.ui.control.Label
        ubdotOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_19               matlab.ui.control.Label
        VehicleBodyRatesPanel          matlab.ui.container.Panel
        rbOutEditField                 matlab.ui.control.NumericEditField
        AlphadegLabel_18               matlab.ui.control.Label
        qbOutEditField                 matlab.ui.control.NumericEditField
        AlphadegLabel_17               matlab.ui.control.Label
        pbOutEditField                 matlab.ui.control.NumericEditField
        AlphadegLabel_16               matlab.ui.control.Label
        VehicleOrientationPanel        matlab.ui.container.Panel
        PsidotOutEditField             matlab.ui.control.NumericEditField
        AlphadegLabel_77               matlab.ui.control.Label
        ThetadotOutEditField           matlab.ui.control.NumericEditField
        AlphadegLabel_76               matlab.ui.control.Label
        PhidotOutEditField             matlab.ui.control.NumericEditField
        AlphadegLabel_75               matlab.ui.control.Label
        HdotOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_15               matlab.ui.control.Label
        HeadingOutEditField            matlab.ui.control.NumericEditField
        AlphadegLabel_14               matlab.ui.control.Label
        GammaOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_13               matlab.ui.control.Label
        BankOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_12               matlab.ui.control.Label
        PsiOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_11               matlab.ui.control.Label
        ThetaOutEditField              matlab.ui.control.NumericEditField
        AlphadegLabel_10               matlab.ui.control.Label
        PhiOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_9                matlab.ui.control.Label
        FlightConditionPanel           matlab.ui.container.Panel
        KEASOutEditField               matlab.ui.control.NumericEditField
        AlphadegLabel_71               matlab.ui.control.Label
        qbarOutEditField               matlab.ui.control.NumericEditField
        MachEditField_2Label_2         matlab.ui.control.Label
        ThrottleOutEditField           matlab.ui.control.NumericEditField
        MachEditField_2Label_9         matlab.ui.control.Label
        BetaOutEditField               matlab.ui.control.NumericEditField
        MachEditField_2Label_8         matlab.ui.control.Label
        AlphaOutEditField              matlab.ui.control.NumericEditField
        MachEditField_2Label_7         matlab.ui.control.Label
        wbOutEditField                 matlab.ui.control.NumericEditField
        MachEditField_2Label_6         matlab.ui.control.Label
        vbOutEditField                 matlab.ui.control.NumericEditField
        MachEditField_2Label_5         matlab.ui.control.Label
        ubOutEditField                 matlab.ui.control.NumericEditField
        MachEditField_2Label_4         matlab.ui.control.Label
        VairOutEditField               matlab.ui.control.NumericEditField
        MachEditField_2Label_3         matlab.ui.control.Label
        MachOutEditField               matlab.ui.control.NumericEditField
        MachEditField_2Label           matlab.ui.control.Label
        AltOutEditField                matlab.ui.control.NumericEditField
        AlphadegLabel_8                matlab.ui.control.Label
        LongitudinalStabilityDerivativesTab  matlab.ui.container.Tab
        DimensionalLongitudinalStabilityDerivativesPanel  matlab.ui.container.Panel
        MdREditField                   matlab.ui.control.NumericEditField
        XAlphafts2Label_24             matlab.ui.control.Label
        ZdREditField                   matlab.ui.control.NumericEditField
        XAlphafts2Label_23             matlab.ui.control.Label
        XdREditField                   matlab.ui.control.NumericEditField
        XAlphafts2Label_22             matlab.ui.control.Label
        MdEEditField                   matlab.ui.control.NumericEditField
        XAlphafts2Label_21             matlab.ui.control.Label
        ZdEEditField                   matlab.ui.control.NumericEditField
        XAlphafts2Label_20             matlab.ui.control.Label
        XdEEditField                   matlab.ui.control.NumericEditField
        XAlphafts2Label_19             matlab.ui.control.Label
        MdAEditField                   matlab.ui.control.NumericEditField
        XAlphafts2Label_18             matlab.ui.control.Label
        ZdAEditField                   matlab.ui.control.NumericEditField
        XAlphafts2Label_17             matlab.ui.control.Label
        XdAEditField                   matlab.ui.control.NumericEditField
        XAlphafts2Label_16             matlab.ui.control.Label
        MdTHREditField                 matlab.ui.control.NumericEditField
        XAlphafts2Label_15             matlab.ui.control.Label
        ZdTHREditField                 matlab.ui.control.NumericEditField
        XAlphafts2Label_14             matlab.ui.control.Label
        XdTHREditField                 matlab.ui.control.NumericEditField
        XAlphafts2Label_13             matlab.ui.control.Label
        MalpdotEditField               matlab.ui.control.NumericEditField
        XAlphafts2Label_12             matlab.ui.control.Label
        ZalpdotEditField               matlab.ui.control.NumericEditField
        XAlphafts2Label_11             matlab.ui.control.Label
        XalpdotEditField               matlab.ui.control.NumericEditField
        XAlphafts2Label_10             matlab.ui.control.Label
        MqEditField                    matlab.ui.control.NumericEditField
        XAlphafts2Label_9              matlab.ui.control.Label
        ZqEditField                    matlab.ui.control.NumericEditField
        XAlphafts2Label_8              matlab.ui.control.Label
        XqEditField                    matlab.ui.control.NumericEditField
        XAlphafts2Label_7              matlab.ui.control.Label
        MUEditField                    matlab.ui.control.NumericEditField
        XAlphafts2Label_6              matlab.ui.control.Label
        ZUEditField                    matlab.ui.control.NumericEditField
        XAlphafts2Label_5              matlab.ui.control.Label
        XUEditField                    matlab.ui.control.NumericEditField
        XAlphafts2Label_4              matlab.ui.control.Label
        MAlphaEditField                matlab.ui.control.NumericEditField
        XAlphafts2Label_3              matlab.ui.control.Label
        ZAlphaEditField                matlab.ui.control.NumericEditField
        XAlphafts2Label_2              matlab.ui.control.Label
        XAlphaEditField                matlab.ui.control.NumericEditField
        XAlphafts2Label                matlab.ui.control.Label
        NonDimLonStabDerivPanel        matlab.ui.container.Panel
        CMdREditField                  matlab.ui.control.NumericEditField
        CLULabel_13                    matlab.ui.control.Label
        CDdREditField                  matlab.ui.control.NumericEditField
        CLULabel_12                    matlab.ui.control.Label
        CLdREditField                  matlab.ui.control.NumericEditField
        CLULabel_11                    matlab.ui.control.Label
        CMdEEditField                  matlab.ui.control.NumericEditField
        CLULabel_10                    matlab.ui.control.Label
        CDdEEditField                  matlab.ui.control.NumericEditField
        CLULabel_9                     matlab.ui.control.Label
        CLdEEditField                  matlab.ui.control.NumericEditField
        CLULabel_8                     matlab.ui.control.Label
        CMdAEditField                  matlab.ui.control.NumericEditField
        CMdALabel                      matlab.ui.control.Label
        CDdAEditField                  matlab.ui.control.NumericEditField
        CDdA1radEditFieldLabel         matlab.ui.control.Label
        CLdAEditField                  matlab.ui.control.NumericEditField
        CLdA1radEditFieldLabel         matlab.ui.control.Label
        CMdTHREditField                matlab.ui.control.NumericEditField
        CMdTHREditFieldLabel           matlab.ui.control.Label
        CDdTHREditField                matlab.ui.control.NumericEditField
        CDAlpha1radLabel_4             matlab.ui.control.Label
        CLdTHREditField                matlab.ui.control.NumericEditField
        CDAlpha1radLabel_3             matlab.ui.control.Label
        CMAlpdotEditField              matlab.ui.control.NumericEditField
        CLULabel_7                     matlab.ui.control.Label
        CDAlpdotEditField              matlab.ui.control.NumericEditField
        CLULabel_6                     matlab.ui.control.Label
        CLULabel_5                     matlab.ui.control.Label
        CLULabel_4                     matlab.ui.control.Label
        CMUEditFieldLabel              matlab.ui.control.Label
        CDUEditFieldLabel              matlab.ui.control.Label
        CLAlpdotEditField              matlab.ui.control.NumericEditField
        CLULabel_3                     matlab.ui.control.Label
        CMqEditField                   matlab.ui.control.NumericEditField
        CDqEditField                   matlab.ui.control.NumericEditField
        CLqEditField                   matlab.ui.control.NumericEditField
        CLULabel_2                     matlab.ui.control.Label
        CMUEditField                   matlab.ui.control.NumericEditField
        CDUEditField                   matlab.ui.control.NumericEditField
        CLUEditField                   matlab.ui.control.NumericEditField
        CLULabel                       matlab.ui.control.Label
        CMAlphaEditField               matlab.ui.control.NumericEditField
        CDAlpha1radLabel_2             matlab.ui.control.Label
        CDAlphaEditField               matlab.ui.control.NumericEditField
        CDAlpha1radLabel               matlab.ui.control.Label
        CLAlphaEditField               matlab.ui.control.NumericEditField
        CLAlpha1radEditFieldLabel      matlab.ui.control.Label
        LatDirStabilityDerivativesTab  matlab.ui.container.Tab
        PrimedDimensionalLatDirStabilityDerivativesPanel  matlab.ui.container.Panel
        RpdREditField                  matlab.ui.control.NumericEditField
        CnBeta1radLabel_53             matlab.ui.control.Label
        NpdREditField                  matlab.ui.control.NumericEditField
        CnBeta1radLabel_52             matlab.ui.control.Label
        RpdEEditField                  matlab.ui.control.NumericEditField
        CnBeta1radLabel_51             matlab.ui.control.Label
        NpdEEditField                  matlab.ui.control.NumericEditField
        CnBeta1radLabel_50             matlab.ui.control.Label
        RpdAEditField                  matlab.ui.control.NumericEditField
        CnBeta1radLabel_49             matlab.ui.control.Label
        NpdAEditField                  matlab.ui.control.NumericEditField
        CnBeta1radLabel_48             matlab.ui.control.Label
        RpdTHREditField                matlab.ui.control.NumericEditField
        CnBeta1radLabel_47             matlab.ui.control.Label
        NpdTHREditField                matlab.ui.control.NumericEditField
        CnBeta1radLabel_46             matlab.ui.control.Label
        RpbdotEditField                matlab.ui.control.NumericEditField
        CnBeta1radLabel_45             matlab.ui.control.Label
        NpbdotEditField                matlab.ui.control.NumericEditField
        CnBeta1radLabel_44             matlab.ui.control.Label
        RprEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_43             matlab.ui.control.Label
        NprEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_42             matlab.ui.control.Label
        RppEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_41             matlab.ui.control.Label
        NppEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_40             matlab.ui.control.Label
        RpBetaEditField                matlab.ui.control.NumericEditField
        CnBeta1radLabel_39             matlab.ui.control.Label
        NpBetaEditField                matlab.ui.control.NumericEditField
        CnBeta1radLabel_38             matlab.ui.control.Label
        DimensionalLatDirStabilityDerivativesPanel  matlab.ui.container.Panel
        YdREditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_37             matlab.ui.control.Label
        RdREditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_36             matlab.ui.control.Label
        NdREditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_35             matlab.ui.control.Label
        YdEEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_34             matlab.ui.control.Label
        RdEEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_33             matlab.ui.control.Label
        NdEEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_32             matlab.ui.control.Label
        YdAEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_31             matlab.ui.control.Label
        RdAEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_30             matlab.ui.control.Label
        NdAEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_29             matlab.ui.control.Label
        YdTHREditField                 matlab.ui.control.NumericEditField
        CnBeta1radLabel_28             matlab.ui.control.Label
        RdTHREditField                 matlab.ui.control.NumericEditField
        CnBeta1radLabel_27             matlab.ui.control.Label
        NdTHREditField                 matlab.ui.control.NumericEditField
        CnBeta1radLabel_26             matlab.ui.control.Label
        YbetadotEditField              matlab.ui.control.NumericEditField
        CnBeta1radLabel_25             matlab.ui.control.Label
        RbetadotEditField              matlab.ui.control.NumericEditField
        CnBeta1radLabel_24             matlab.ui.control.Label
        NbetadotEditField              matlab.ui.control.NumericEditField
        CnBeta1radLabel_23             matlab.ui.control.Label
        YrEditField                    matlab.ui.control.NumericEditField
        CnBeta1radLabel_22             matlab.ui.control.Label
        RrEditField                    matlab.ui.control.NumericEditField
        CnBeta1radLabel_21             matlab.ui.control.Label
        NrEditField                    matlab.ui.control.NumericEditField
        CnBeta1radLabel_20             matlab.ui.control.Label
        YpEditField                    matlab.ui.control.NumericEditField
        CnBeta1radLabel_19             matlab.ui.control.Label
        RpEditField                    matlab.ui.control.NumericEditField
        CnBeta1radLabel_18             matlab.ui.control.Label
        NpEditField                    matlab.ui.control.NumericEditField
        CnBeta1radLabel_17             matlab.ui.control.Label
        YBetaEditField                 matlab.ui.control.NumericEditField
        CnBeta1radLabel_16             matlab.ui.control.Label
        RBetaEditField                 matlab.ui.control.NumericEditField
        CnBeta1radLabel_15             matlab.ui.control.Label
        NBetaEditField                 matlab.ui.control.NumericEditField
        CnBeta1radLabel_14             matlab.ui.control.Label
        NonDimensionalLatDirStabilityDerivativesPanel  matlab.ui.container.Panel
        CYdREditField                  matlab.ui.control.NumericEditField
        CndE1radLabel_6                matlab.ui.control.Label
        CrdREditField                  matlab.ui.control.NumericEditField
        CndE1radLabel_5                matlab.ui.control.Label
        CndREditField                  matlab.ui.control.NumericEditField
        CndE1radLabel_4                matlab.ui.control.Label
        CYdEEditField                  matlab.ui.control.NumericEditField
        CndE1radLabel_3                matlab.ui.control.Label
        CrdEEditField                  matlab.ui.control.NumericEditField
        CndE1radLabel_2                matlab.ui.control.Label
        CndEEditField                  matlab.ui.control.NumericEditField
        CndE1radLabel                  matlab.ui.control.Label
        CYdAEditField                  matlab.ui.control.NumericEditField
        CrdA1radLabel_2                matlab.ui.control.Label
        CrdAEditField                  matlab.ui.control.NumericEditField
        CrdA1radLabel                  matlab.ui.control.Label
        CndAEditField                  matlab.ui.control.NumericEditField
        CndA1radEditFieldLabel         matlab.ui.control.Label
        CYdTHREditField                matlab.ui.control.NumericEditField
        CYdTHREditFieldLabel           matlab.ui.control.Label
        CrdTHREditField                matlab.ui.control.NumericEditField
        CrdTHREditFieldLabel           matlab.ui.control.Label
        CndTHREditField                matlab.ui.control.NumericEditField
        CnBeta1radLabel_13             matlab.ui.control.Label
        CYBetadotEditField             matlab.ui.control.NumericEditField
        CnBeta1radLabel_12             matlab.ui.control.Label
        CrBetadotEditField             matlab.ui.control.NumericEditField
        CnBeta1radLabel_11             matlab.ui.control.Label
        CnBetadotEditField             matlab.ui.control.NumericEditField
        CnBeta1radLabel_10             matlab.ui.control.Label
        CYrEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_9              matlab.ui.control.Label
        CrrEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_8              matlab.ui.control.Label
        CnrEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_7              matlab.ui.control.Label
        CYpEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_6              matlab.ui.control.Label
        CrpEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_5              matlab.ui.control.Label
        CnpEditField                   matlab.ui.control.NumericEditField
        CnBeta1radLabel_4              matlab.ui.control.Label
        CYBetaEditField                matlab.ui.control.NumericEditField
        CnBeta1radLabel_3              matlab.ui.control.Label
        CrBetaEditField                matlab.ui.control.NumericEditField
        CnBeta1radLabel_2              matlab.ui.control.Label
        CnBetaEditField                matlab.ui.control.NumericEditField
        CnBeta1radLabel                matlab.ui.control.Label
        LinearSystemTab                matlab.ui.container.Tab
        LatDirPanel                    matlab.ui.container.Panel
        LatTau4EditField               matlab.ui.control.EditField
        LatTau3EditField               matlab.ui.control.EditField
        LatTau2EditField               matlab.ui.control.EditField
        LatTau1EditField               matlab.ui.control.EditField
        LatT4EditField                 matlab.ui.control.EditField
        LatT3EditField                 matlab.ui.control.EditField
        LatT2EditField                 matlab.ui.control.EditField
        LatT1EditField                 matlab.ui.control.EditField
        LatNatFreqLabel_2              matlab.ui.control.Label
        LatTTD4EditField               matlab.ui.control.EditField
        LatTTD3EditField               matlab.ui.control.EditField
        LatTTD2EditField               matlab.ui.control.EditField
        LatTTD1EditField               matlab.ui.control.EditField
        LatEig4EditField               matlab.ui.control.EditField
        LatEig3EditField               matlab.ui.control.EditField
        LatEig2EditField               matlab.ui.control.EditField
        LatEig1EditField               matlab.ui.control.EditField
        LatControl                     matlab.ui.control.Image
        LatStates                      matlab.ui.control.Image
        LatTimeToDoubleLabel           matlab.ui.control.Label
        LatDampingLabel                matlab.ui.control.Label
        LatZeta4EditField              matlab.ui.control.NumericEditField
        LatZeta3EditField              matlab.ui.control.NumericEditField
        LatZeta2EditField              matlab.ui.control.NumericEditField
        LatZeta1EditField              matlab.ui.control.NumericEditField
        LatNatFreqLabel                matlab.ui.control.Label
        LatEigenvaluesLabel            matlab.ui.control.Label
        Blat44EditField                matlab.ui.control.NumericEditField
        Blat43EditField                matlab.ui.control.NumericEditField
        Blat42EditField                matlab.ui.control.NumericEditField
        Blat41EditField                matlab.ui.control.NumericEditField
        Blat34EditField                matlab.ui.control.NumericEditField
        Blat33EditField                matlab.ui.control.NumericEditField
        Blat32EditField                matlab.ui.control.NumericEditField
        Blat31EditField                matlab.ui.control.NumericEditField
        Blat24EditField                matlab.ui.control.NumericEditField
        Blat23EditField                matlab.ui.control.NumericEditField
        Blat22EditField                matlab.ui.control.NumericEditField
        Blat21EditField                matlab.ui.control.NumericEditField
        Blat14EditField                matlab.ui.control.NumericEditField
        Blat13EditField                matlab.ui.control.NumericEditField
        Blat12EditField                matlab.ui.control.NumericEditField
        Blat11EditField                matlab.ui.control.NumericEditField
        BlatLabel                      matlab.ui.control.Label
        Alat44EditField                matlab.ui.control.NumericEditField
        Alat43EditField                matlab.ui.control.NumericEditField
        Alat42EditField                matlab.ui.control.NumericEditField
        Alat41EditField                matlab.ui.control.NumericEditField
        Alat34EditField                matlab.ui.control.NumericEditField
        Alat33EditField                matlab.ui.control.NumericEditField
        Alat32EditField                matlab.ui.control.NumericEditField
        Alat31EditField                matlab.ui.control.NumericEditField
        Alat24EditField                matlab.ui.control.NumericEditField
        Alat23EditField                matlab.ui.control.NumericEditField
        Alat22EditField                matlab.ui.control.NumericEditField
        Alat21EditField                matlab.ui.control.NumericEditField
        Alat14EditField                matlab.ui.control.NumericEditField
        Alat13EditField                matlab.ui.control.NumericEditField
        Alat12EditField                matlab.ui.control.NumericEditField
        Alat11EditField                matlab.ui.control.NumericEditField
        ALatLabel                      matlab.ui.control.Label
        LongitudinalPanel              matlab.ui.container.Panel
        LonTau4EditField               matlab.ui.control.EditField
        LonTau3EditField               matlab.ui.control.EditField
        LonTau2EditField               matlab.ui.control.EditField
        LonTau1EditField               matlab.ui.control.EditField
        LonT4EditField                 matlab.ui.control.EditField
        LonT3EditField                 matlab.ui.control.EditField
        LonT2EditField                 matlab.ui.control.EditField
        LonT1EditField                 matlab.ui.control.EditField
        TauLabel                       matlab.ui.control.Label
        LonTTD4EditField               matlab.ui.control.EditField
        LonTTD3EditField               matlab.ui.control.EditField
        LonTTD2EditField               matlab.ui.control.EditField
        LonTTD1EditField               matlab.ui.control.EditField
        LonEig4EditField               matlab.ui.control.EditField
        LonEig3EditField               matlab.ui.control.EditField
        LonEig2EditField               matlab.ui.control.EditField
        LonStates                      matlab.ui.control.Image
        LonControl                     matlab.ui.control.Image
        LonEig1EditField               matlab.ui.control.EditField
        TimeToDoubleLabel              matlab.ui.control.Label
        DampingLabel                   matlab.ui.control.Label
        LonZeta4EditField              matlab.ui.control.NumericEditField
        LonZeta3EditField              matlab.ui.control.NumericEditField
        LonZeta2EditField              matlab.ui.control.NumericEditField
        LonZeta1EditField              matlab.ui.control.NumericEditField
        PeriodsLabel                   matlab.ui.control.Label
        EigenvaluesLabel               matlab.ui.control.Label
        Blon44EditField                matlab.ui.control.NumericEditField
        Blon43EditField                matlab.ui.control.NumericEditField
        Blon42EditField                matlab.ui.control.NumericEditField
        Blon41EditField                matlab.ui.control.NumericEditField
        Blon34EditField                matlab.ui.control.NumericEditField
        Blon33EditField                matlab.ui.control.NumericEditField
        Blon32EditField                matlab.ui.control.NumericEditField
        Blon31EditField                matlab.ui.control.NumericEditField
        Blon24EditField                matlab.ui.control.NumericEditField
        Blon23EditField                matlab.ui.control.NumericEditField
        Blon22EditField                matlab.ui.control.NumericEditField
        Blon21EditField                matlab.ui.control.NumericEditField
        Blon14EditField                matlab.ui.control.NumericEditField
        Blon13EditField                matlab.ui.control.NumericEditField
        Blon12EditField                matlab.ui.control.NumericEditField
        Blon11EditField                matlab.ui.control.NumericEditField
        BLabel                         matlab.ui.control.Label
        Alon44EditField                matlab.ui.control.NumericEditField
        Alon43EditField                matlab.ui.control.NumericEditField
        Alon42EditField                matlab.ui.control.NumericEditField
        Alon41EditField                matlab.ui.control.NumericEditField
        Alon34EditField                matlab.ui.control.NumericEditField
        Alon33EditField                matlab.ui.control.NumericEditField
        Alon32EditField                matlab.ui.control.NumericEditField
        Alon31EditField                matlab.ui.control.NumericEditField
        Alon24EditField                matlab.ui.control.NumericEditField
        Alon23EditField                matlab.ui.control.NumericEditField
        Alon22EditField                matlab.ui.control.NumericEditField
        Alon21EditField                matlab.ui.control.NumericEditField
        Alon14EditField                matlab.ui.control.NumericEditField
        Alon13EditField                matlab.ui.control.NumericEditField
        Alon12EditField                matlab.ui.control.NumericEditField
        Alon11EditField                matlab.ui.control.NumericEditField
        ALabel                         matlab.ui.control.Label
        InputsTabGroup                 matlab.ui.container.TabGroup
        TrimInputsTab                  matlab.ui.container.Tab
        RbEditField                    matlab.ui.control.NumericEditField
        GammadegLabel_7                matlab.ui.control.Label
        QbEditField                    matlab.ui.control.NumericEditField
        GammadegLabel_6                matlab.ui.control.Label
        PbEditField                    matlab.ui.control.NumericEditField
        GammadegLabel_5                matlab.ui.control.Label
        dLEFEditField                  matlab.ui.control.NumericEditField
        AlphadegLabel_7                matlab.ui.control.Label
        ZcgBiasEditField               matlab.ui.control.NumericEditField
        YcgBiasftLabel_2               matlab.ui.control.Label
        YcgBiasEditField               matlab.ui.control.NumericEditField
        YcgBiasftLabel                 matlab.ui.control.Label
        XcgBiasEditField               matlab.ui.control.NumericEditField
        XcgBiasftEditFieldLabel        matlab.ui.control.Label
        WeightBiasEditField            matlab.ui.control.NumericEditField
        GammadegLabel_4                matlab.ui.control.Label
        HeadingEditField               matlab.ui.control.NumericEditField
        GammadegLabel_2                matlab.ui.control.Label
        GammaEditField                 matlab.ui.control.NumericEditField
        GammadegLabel                  matlab.ui.control.Label
        BankEditField                  matlab.ui.control.NumericEditField
        BankdegEditFieldLabel          matlab.ui.control.Label
        MachEditField                  matlab.ui.control.NumericEditField
        AlphadegLabel_6                matlab.ui.control.Label
        AltEditField                   matlab.ui.control.NumericEditField
        AlphadegLabel_5                matlab.ui.control.Label
        drIGEditField                  matlab.ui.control.NumericEditField
        drEditField                    matlab.ui.control.NumericEditField
        AlphadegLabel_4                matlab.ui.control.Label
        deIGEditField                  matlab.ui.control.NumericEditField
        deEditField                    matlab.ui.control.NumericEditField
        AlphadegLabel_3                matlab.ui.control.Label
        daIGEditField                  matlab.ui.control.NumericEditField
        daEditField                    matlab.ui.control.NumericEditField
        AlphadegLabel_2                matlab.ui.control.Label
        AlphaIGEditField               matlab.ui.control.NumericEditField
        AlphaEditField                 matlab.ui.control.NumericEditField
        AlphadegLabel                  matlab.ui.control.Label
        BetaIGEditField                matlab.ui.control.NumericEditField
        BetaEditField                  matlab.ui.control.NumericEditField
        BetadegEditFieldLabel          matlab.ui.control.Label
        ThrottleIGEditField            matlab.ui.control.NumericEditField
        InitialGuessLabel              matlab.ui.control.Label
        FixedLabel                     matlab.ui.control.Label
        ThrottleEditField              matlab.ui.control.NumericEditField
        ThrottleLabel                  matlab.ui.control.Label
        StabilitySettingsTab           matlab.ui.container.Tab
        dRudEditField                  matlab.ui.control.NumericEditField
        dVairfpsLabel_7                matlab.ui.control.Label
        dElevEditField                 matlab.ui.control.NumericEditField
        dVairfpsLabel_6                matlab.ui.control.Label
        dAilEditField                  matlab.ui.control.NumericEditField
        dVairfpsLabel_5                matlab.ui.control.Label
        drStabEditField                matlab.ui.control.NumericEditField
        dVairfpsLabel_4                matlab.ui.control.Label
        dqStabEditField                matlab.ui.control.NumericEditField
        dVairfpsLabel_3                matlab.ui.control.Label
        dpStabEditField                matlab.ui.control.NumericEditField
        dVairfpsLabel_2                matlab.ui.control.Label
        dVairEditField                 matlab.ui.control.NumericEditField
        dVairfpsLabel                  matlab.ui.control.Label
        dAlphaEditField                matlab.ui.control.NumericEditField
        dAlphadegEditFieldLabel        matlab.ui.control.Label
        dBetaEditField                 matlab.ui.control.NumericEditField
        dBetadegEditFieldLabel         matlab.ui.control.Label
        dThrottleEditField             matlab.ui.control.NumericEditField
        dThrottleLabel                 matlab.ui.control.Label
        PayloadStateButtonGroup        matlab.ui.container.ButtonGroup
        DeployedButton                 matlab.ui.control.RadioButton
        StowedButton                   matlab.ui.control.RadioButton
        GearModeButtonGroup            matlab.ui.container.ButtonGroup
        DownButton                     matlab.ui.control.RadioButton
        UpButton                       matlab.ui.control.RadioButton
        TrimOptionsPanel               matlab.ui.container.Panel
        rbdotCheckBox                  matlab.ui.control.CheckBox
        qbdotCheckBox                  matlab.ui.control.CheckBox
        pbdotCheckBox                  matlab.ui.control.CheckBox
        wbdotCheckBox                  matlab.ui.control.CheckBox
        vbdotCheckBox                  matlab.ui.control.CheckBox
        ubdotCheckBox                  matlab.ui.control.CheckBox
        StaticStabilityandControlTab   matlab.ui.container.Tab
        LinearAnalysisTab              matlab.ui.container.Tab
        TRIMLATSubLabel                matlab.ui.control.Label
        TRIMLATLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        % These are globally defined everywhere
        Trim_mdl;   % Trim model
        TrimPath;   % Trim model path 
        trimIn;     % Inputs into single trim routine 
        trimOut;    % Outputs for single trim routine 
    end
    
    methods (Access = private)
        
        function dataOut = processSimData(app,simData)
            for idx = 1:simData.sigsOut.numElements
                paramName = simData.sigsOut{idx}.Name;
                try
                    dataOut.(paramName) = simData.sigsOut{idx}.Values.Data;
                catch
                    paramNameNew = paramName(2:end-1);
                    dataOut.(paramNameNew) = simData.sigsOut{idx}.Values.Data;
                end
            end
        end
        
        function resetOutputs(app)
            %% Output trim results to the app window
            % -- Flight Condition
            app.AltOutEditField.Value       = 0;
            app.MachOutEditField.Value      = 0;
            app.VairOutEditField.Value      = 0;
            app.ubOutEditField.Value        = 0;
            app.vbOutEditField.Value        = 0;
            app.wbOutEditField.Value        = 0;
            app.AlphaOutEditField.Value     = 0;
            app.BetaOutEditField.Value      = 0;
            app.ThrottleOutEditField.Value  = 0;
            % -- Vehicle Orientation 
            app.PhiOutEditField.Value       = 0;
            app.ThetaOutEditField.Value     = 0;
            app.PsiOutEditField.Value       = 0;
            app.BankOutEditField.Value      = 0;
            app.GammaOutEditField.Value     = 0;
            app.HeadingOutEditField.Value   = 0;
            app.HdotOutEditField.Value      = 0;
            % -- Vehicle Body Rates
            app.pbOutEditField.Value        = 0;
            app.qbOutEditField.Value        = 0;
            app.rbOutEditField.Value        = 0;
            % -- Vehicle Body Accelerations
            app.ubdotOutEditField.Value     = 0;
            app.vbdotOutEditField.Value     = 0;
            app.wbdotOutEditField.Value     = 0;
            app.pbdotOutEditField.Value     = 0;
            app.qbdotOutEditField.Value     = 0;
            app.rbdotOutEditField.Value     = 0;
            app.NxOutEditField.Value        = 0;
            app.NyOutEditField.Value        = 0;
            app.NzOutEditField.Value        = 0;
            % -- Environment Data 
            app.qbarOutEditField.Value      = 0;
            app.tempOutEditField.Value      = 0;
            app.PairOutEditField.Value      = 0;
            app.KEASOutEditField.Value      = 0;
            app.SoSOutEditField.Value       = 0;
            app.rhoOutEditField.Value       = 0;
            % -- Control Surfaces
            app.daOutEditField.Value        = 0;
            app.deOutEditField.Value        = 0;
            app.drOutEditField.Value        = 0;
            app.dlefOutEditField.Value      = 0;
            % -- Aerodynamics
            app.XaeroOutEditField.Value     = 0;
            app.YaeroOutEditField.Value     = 0;
            app.ZaeroOutEditField.Value     = 0;
            app.LaeroOutEditField.Value     = 0;
            app.MaeroOutEditField.Value     = 0;
            app.NaeroOutEditField.Value     = 0;
            app.LiftOutEditField.Value      = 0;
            app.DragOutEditField.Value      = 0;
            app.LoDOutEditField.Value       = 0;
            % -- Propulsion
            app.XpropOutEditField.Value     = 0;
            app.YpropOutEditField.Value     = 0;
            app.ZpropOutEditField.Value     = 0;
            app.LpropOutEditField.Value     = 0;
            app.MpropOutEditField.Value     = 0;
            app.NpropOutEditField.Value     = 0;
            app.ThrustOutEditField.Value    = 0;
            app.WdotOutEditField.Value      = 0;
            % -- Total Forces and Moments
            app.XtotOutEditField.Value      = 0;
            app.YtotOutEditField.Value      = 0;
            app.ZtotOutEditField.Value      = 0;
            app.LtotOutEditField.Value      = 0;
            app.MtotOutEditField.Value      = 0;
            app.NtotOutEditField.Value      = 0;
            % -- Mass Properties
            app.weightOutEditField.Value    = 0;
            app.massOutEditField.Value      = 0;
            app.xcgOutEditField.Value       = 0;
            app.ycgOutEditField.Value       = 0;
            app.zcgOutEditField.Value       = 0;
            app.ixxOutEditField.Value       = 0;
            app.iyyOutEditField.Value       = 0;
            app.izzOutEditField.Value       = 0;
            app.ixyOutEditField.Value       = 0;
            app.ixzOutEditField.Value       = 0;
            app.iyzOutEditField.Value       = 0;
            % ------------------------------------------------------------
            % Non-dimensional Longitudinal Stability Derivatives 
            % -- Alpha 
            app.CLAlphaEditField.Value      = 0;
            app.CDAlphaEditField.Value      = 0;
            app.CMAlphaEditField.Value      = 0;
            % -- Speed 
            app.CLUEditField.Value          = 0;
            app.CDUEditField.Value          = 0;
            app.CMUEditField.Value          = 0;
            % -- Pitch Rate
            app.CLqEditField.Value          = 0;
            app.CDqEditField.Value          = 0;
            app.CMqEditField.Value          = 0;
            % -- Dynamic Derivative
            app.CLAlpdotEditField.Value     = 0;
            app.CDAlpdotEditField.Value     = 0;
            app.CMAlpdotEditField.Value     = 0;
            % -- Throttle 
            app.CLdTHREditField.Value       = 0;
            app.CDdTHREditField.Value       = 0;
            app.CMdTHREditField.Value       = 0;
            % -- Ailerion 
            app.CLdAEditField.Value         = 0;
            app.CDdAEditField.Value         = 0;
            app.CMdAEditField.Value         = 0;
            % -- Elevator
            app.CLdEEditField.Value         = 0;
            app.CDdEEditField.Value         = 0;
            app.CMdEEditField.Value         = 0;
            % -- Rudder
            app.CLdREditField.Value         = 0;
            app.CDdREditField.Value         = 0;
            app.CMdREditField.Value         = 0;
            % ------------------------------------------------------------
            % Dimensional Longitudinal Stability Derivatives 
            app.XAlphaEditField.Value       = 0;
            app.ZAlphaEditField.Value       = 0;
            app.MAlphaEditField.Value       = 0;
            app.XUEditField.Value           = 0;
            app.ZUEditField.Value           = 0;
            app.MUEditField.Value           = 0;
            app.XqEditField.Value           = 0;
            app.ZqEditField.Value           = 0;
            app.MqEditField.Value           = 0;
            app.XalpdotEditField.Value      = 0;
            app.ZalpdotEditField.Value      = 0;
            app.MalpdotEditField.Value      = 0;
            app.XdTHREditField.Value        = 0;
            app.ZdTHREditField.Value        = 0;
            app.MdTHREditField.Value        = 0;
            app.XdAEditField.Value          = 0;
            app.ZdAEditField.Value          = 0;
            app.MdAEditField.Value          = 0;
            app.XdEEditField.Value          = 0;
            app.ZdEEditField.Value          = 0;
            app.MdEEditField.Value          = 0;
            app.XdREditField.Value          = 0;
            app.ZdREditField.Value          = 0;
            app.MdREditField.Value          = 0;
            % ------------------------------------------------------------
            % Non-dimensional Lat/Dir Stabiity Derivative
            % -- Beta
            app.CnBetaEditField.Value       = 0;
            app.CrBetaEditField.Value       = 0;
            app.CYBetaEditField.Value       = 0;
            % -- Roll Rate 
            app.CnpEditField.Value          = 0;
            app.CrpEditField.Value          = 0;
            app.CYpEditField.Value          = 0;
            % -- Yaw Rate
            app.CnrEditField.Value          = 0;
            app.CrrEditField.Value          = 0;
            app.CYrEditField.Value          = 0;
            % -- Dynamic Derivative
            app.CnBetadotEditField.Value    = 0;
            app.CrBetadotEditField.Value    = 0;
            app.CYBetadotEditField.Value    = 0;
            % -- Throttle 
            app.CndTHREditField.Value       = 0;
            app.CrdTHREditField.Value       = 0;
            app.CYdTHREditField.Value       = 0;
            % -- Aileron
            app.CndAEditField.Value         = 0;
            app.CrdAEditField.Value         = 0;
            app.CYdAEditField.Value         = 0;
            % -- Elevator
            app.CndEEditField.Value         = 0;
            app.CrdEEditField.Value         = 0;
            app.CYdEEditField.Value         = 0;
            % -- Rudder 
            app.CndREditField.Value         = 0;
            app.CrdREditField.Value         = 0;
            app.CYdREditField.Value         = 0;
            % ------------------------------------------------------------
            % Dimensional Lat/Dir Stability Derivatives
            app.NBetaEditField.Value        = 0;
            app.RBetaEditField.Value        = 0;
            app.YBetaEditField.Value        = 0;
            app.NpEditField.Value           = 0;
            app.RpEditField.Value           = 0;
            app.YpEditField.Value           = 0;
            app.NrEditField.Value           = 0;
            app.RrEditField.Value           = 0;
            app.YrEditField.Value           = 0;
            app.NbetadotEditField.Value     = 0;
            app.RbetadotEditField.Value     = 0;
            app.YbetadotEditField.Value     = 0;
            app.NdTHREditField.Value        = 0;
            app.RdTHREditField.Value        = 0;
            app.YdTHREditField.Value        = 0;
            app.NdAEditField.Value          = 0;
            app.RdAEditField.Value          = 0;
            app.YdAEditField.Value          = 0;
            app.NdEEditField.Value          = 0;
            app.RdEEditField.Value          = 0;
            app.YdEEditField.Value          = 0;
            app.NdREditField.Value          = 0;
            app.RdREditField.Value          = 0;
            app.YdREditField.Value          = 0;
            % Primed Dimensional Lat/Dir Stability Derivatives 
            app.NpBetaEditField.Value       = 0;
            app.RpBetaEditField.Value       = 0;
            app.NppEditField.Value          = 0;
            app.RppEditField.Value          = 0;
            app.NprEditField.Value          = 0;
            app.RprEditField.Value          = 0;
            app.NpbdotEditField.Value       = 0;
            app.RpbdotEditField.Value       = 0;
            app.NpdTHREditField.Value       = 0;
            app.RpdTHREditField.Value       = 0;
            app.NpdAEditField.Value         = 0;
            app.RpdAEditField.Value         = 0;
            app.NpdEEditField.Value         = 0;
            app.RpdEEditField.Value         = 0;
            app.NpdREditField.Value         = 0;
            app.RpdREditField.Value         = 0;
            %% Longitudinal Linear System 
            % -- A Matrix
            app.Alon11EditField.Value       = 0;
            app.Alon12EditField.Value       = 0;
            app.Alon13EditField.Value       = 0;
            app.Alon14EditField.Value       = 0;
            app.Alon21EditField.Value       = 0;
            app.Alon22EditField.Value       = 0;
            app.Alon23EditField.Value       = 0;
            app.Alon24EditField.Value       = 0;
            app.Alon31EditField.Value       = 0;
            app.Alon32EditField.Value       = 0;
            app.Alon33EditField.Value       = 0;
            app.Alon34EditField.Value       = 0;
            app.Alon41EditField.Value       = 0;
            app.Alon42EditField.Value       = 0;
            app.Alon43EditField.Value       = 0;
            app.Alon44EditField.Value       = 0;
            % -- B Matrix
            app.Blon11EditField.Value       = 0;
            app.Blon12EditField.Value       = 0;
            app.Blon13EditField.Value       = 0;
            app.Blon14EditField.Value       = 0;
            app.Blon21EditField.Value       = 0;
            app.Blon22EditField.Value       = 0;
            app.Blon23EditField.Value       = 0;
            app.Blon24EditField.Value       = 0;
            app.Blon31EditField.Value       = 0;
            app.Blon32EditField.Value       = 0;
            app.Blon33EditField.Value       = 0;
            app.Blon34EditField.Value       = 0;
            app.Blon41EditField.Value       = 0;
            app.Blon42EditField.Value       = 0;
            app.Blon43EditField.Value       = 0;
            app.Blon44EditField.Value       = 0;
            % -- Longitudinal Stability Characteristics 
            app.LonEig1EditField.Value      = num2str(0);
            app.LonEig2EditField.Value      = num2str(0);
            app.LonEig3EditField.Value      = num2str(0);
            app.LonEig4EditField.Value      = num2str(0);
            app.LonT1EditField.Value        = num2str(0);
            app.LonT2EditField.Value        = num2str(0);
            app.LonT3EditField.Value        = num2str(0);
            app.LonT4EditField.Value        = num2str(0);
            app.LonZeta1EditField.Value     = 0;
            app.LonZeta2EditField.Value     = 0;
            app.LonZeta3EditField.Value     = 0;
            app.LonZeta4EditField.Value     = 0;
            app.LonTTD1EditField.Value      = num2str(0);
            app.LonTTD2EditField.Value      = num2str(0);
            app.LonTTD3EditField.Value      = num2str(0);
            app.LonTTD4EditField.Value      = num2str(0);
            app.LonTau1EditField.Value      = num2str(0);
            app.LonTau2EditField.Value      = num2str(0);
            app.LonTau3EditField.Value      = num2str(0);
            app.LonTau4EditField.Value      = num2str(0);
            %% Lat/Dir Linear System
            % -- A Matrix
            app.Alat11EditField.Value       = 0;
            app.Alat12EditField.Value       = 0;
            app.Alat13EditField.Value       = 0;
            app.Alat14EditField.Value       = 0;
            app.Alat21EditField.Value       = 0;
            app.Alat22EditField.Value       = 0;
            app.Alat23EditField.Value       = 0;
            app.Alat24EditField.Value       = 0;
            app.Alat31EditField.Value       = 0;
            app.Alat32EditField.Value       = 0;
            app.Alat33EditField.Value       = 0;
            app.Alat34EditField.Value       = 0;
            app.Alat41EditField.Value       = 0;
            app.Alat42EditField.Value       = 0;
            app.Alat43EditField.Value       = 0;
            app.Alat44EditField.Value       = 0;
            % -- B Matrix
            app.Blat11EditField.Value       = 0;
            app.Blat12EditField.Value       = 0;
            app.Blat13EditField.Value       = 0;
            app.Blat14EditField.Value       = 0;
            app.Blat21EditField.Value       = 0;
            app.Blat22EditField.Value       = 0;
            app.Blat23EditField.Value       = 0;
            app.Blat24EditField.Value       = 0;
            app.Blat31EditField.Value       = 0;
            app.Blat32EditField.Value       = 0;
            app.Blat33EditField.Value       = 0;
            app.Blat34EditField.Value       = 0;
            app.Blat41EditField.Value       = 0;
            app.Blat42EditField.Value       = 0;
            app.Blat43EditField.Value       = 0;
            app.Blat44EditField.Value       = 0;
            % -- Lat/Dir Stability Characteristics 
            app.LatEig1EditField.Value      = num2str(0);
            app.LatEig2EditField.Value      = num2str(0);
            app.LatEig3EditField.Value      = num2str(0);
            app.LatEig4EditField.Value      = num2str(0);
            app.LatT1EditField.Value        = num2str(0);
            app.LatT2EditField.Value        = num2str(0);
            app.LatT3EditField.Value        = num2str(0);
            app.LatT4EditField.Value        = num2str(0);
            app.LatZeta1EditField.Value     = 0;
            app.LatZeta2EditField.Value     = 0;
            app.LatZeta3EditField.Value     = 0;
            app.LatZeta4EditField.Value     = 0;
            app.LatTTD1EditField.Value      = num2str(0);
            app.LatTTD2EditField.Value      = num2str(0);
            app.LatTTD3EditField.Value      = num2str(0);
            app.LatTTD4EditField.Value      = num2str(0);
            app.LatTau1EditField.Value      = num2str(0);
            app.LatTau2EditField.Value      = num2str(0);
            app.LatTau3EditField.Value      = num2str(0);
            app.LatTau4EditField.Value      = num2str(0);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrimVehicleButton
        function TrimVehicleButtonPushed(app, event)
            % Reset trim output displays 
            resetOutputs(app);
            % Reset trim lamp 
            app.SuccessfulTrimLamp.Color = [0.65 0.65 0.65];

            % Check to ensure trim model is loaded 
            if isempty(app.Trim_mdl)
                uialert(app.UIFigure,'Error! Must load Trim Model before trimming','No Selected Trim Model')
                return;
            end
            % Check to ensure at least 1 DOF is selected 
            if app.ubdotCheckBox.Value == 0 && app.vbdotCheckBox.Value == 0 ...
                    && app.wbdotCheckBox.Value == 0 && app.pbdotCheckBox.Value == 0 ...
                    && app.qbdotCheckBox.Value == 0 && app.rbdotCheckBox.Value == 0
                uialert(app.UIFigure,'Error! Must select at least 1 Degree of Freedom','No Selected DOF')
                return;
            end

            % Read in mass data from base workspace 
            massData = evalin('base','massData');
            aeroData = evalin('base','aeroData');

            %% Load user inputs 
            app.trimIn.altitude_ft  = app.AltEditField.Value;
            app.trimIn.mach         = app.MachEditField.Value;
            app.trimIn.dlef_deg     = app.dLEFEditField.Value;
            app.trimIn.bank_deg     = app.BankEditField.Value;
            app.trimIn.gamma_deg    = app.GammaEditField.Value;
            app.trimIn.heading_deg  = app.HeadingEditField.Value;
            app.trimIn.lat_deg      = 0;
            app.trimIn.lon_deg      = 0;
            % Define initial body rates 
            app.trimIn.pb_rps       = app.PbEditField.Value;
            app.trimIn.qb_rps       = app.QbEditField.Value;
            app.trimIn.rb_rps       = app.RbEditField.Value;
            % Define weight and CG biases 
            massData.bias.weight_lbm    = app.WeightBiasEditField.Value;
            massData.bias.xcg_ft        = app.XcgBiasEditField.Value;
            massData.bias.ycg_ft        = app.YcgBiasEditField.Value;
            massData.bias.zcg_ft        = app.ZcgBiasEditField.Value;
            % Assign back out to base workspace for sim use 
            assignin('base','massData',massData)

            %% Trim Model Configuration 
            % Setup waitbar 
            f = waitbar(0,'Initializing Trim...');
            % Load trim model
            load_system(app.Trim_mdl)
            % Configure trim model based on user selections for trim mode
            if app.ubdotCheckBox.Value == 1 % Trim throttle 
                app.trimIn.thr = app.ThrottleIGEditField.Value; % Establish initial guess
                set_param([app.Trim_mdl(1:end-4) '/thrTrim_Sw'],'sw','1');
                set_param([app.Trim_mdl(1:end-4) '/Thr_Solve'],'Commented','off');
                set_param([app.Trim_mdl(1:end-4) '/Thr_Mem'],'Commented','through');
            else
                app.trimIn.thr = app.ThrottleEditField.Value; % Set based on user setting 
                set_param([app.Trim_mdl(1:end-4) '/thrTrim_Sw'],'sw','0');
                set_param([app.Trim_mdl(1:end-4) '/Thr_Solve'],'Commented','through');
                set_param([app.Trim_mdl(1:end-4) '/Thr_Mem'],'Commented','off');
            end
            if app.vbdotCheckBox.Value == 1 % Trim beta 
                app.trimIn.beta_deg = app.BetaIGEditField.Value; % Establish initial guess
                set_param([app.Trim_mdl(1:end-4) '/betTrim_Sw'],'sw','1');
                set_param([app.Trim_mdl(1:end-4) '/Beta_Solve'],'Commented','off');
                set_param([app.Trim_mdl(1:end-4) '/beta_Mem'],'Commented','through');
            else
                app.trimIn.beta_deg = app.BetaEditField.Value; % Set based on user setting 
                set_param([app.Trim_mdl(1:end-4) '/betTrim_Sw'],'sw','0');
                set_param([app.Trim_mdl(1:end-4) '/Beta_Solve'],'Commented','through');
                set_param([app.Trim_mdl(1:end-4) '/beta_Mem'],'Commented','off');
            end
            if app.wbdotCheckBox.Value == 1 % Trim alpha 
                app.trimIn.alpha_deg = app.AlphaIGEditField.Value; % Establish initial guess
                set_param([app.Trim_mdl(1:end-4) '/alpTrim_Sw'],'sw','1');
                set_param([app.Trim_mdl(1:end-4) '/Alpha_Solve'],'Commented','off');
                set_param([app.Trim_mdl(1:end-4) '/alpha_Mem'],'Commented','through');
            else
                app.trimIn.alpha_deg = app.AlphaEditField.Value; % Set based on user setting 
                set_param([app.Trim_mdl(1:end-4) '/alpTrim_Sw'],'sw','0');
                set_param([app.Trim_mdl(1:end-4) '/Alpha_Solve'],'Commented','through');
                set_param([app.Trim_mdl(1:end-4) '/alpha_Mem'],'Commented','off');
            end
            if app.pbdotCheckBox.Value == 1 % Trim DA 
                app.trimIn.da_deg = app.daIGEditField.Value; % Establish initial guess
                set_param([app.Trim_mdl(1:end-4) '/DATrim_Sw'],'sw','1');
                set_param([app.Trim_mdl(1:end-4) '/DA_Solve'],'Commented','off');
                set_param([app.Trim_mdl(1:end-4) '/DA_Mem'],'Commented','through');
            else
                app.trimIn.da_deg = app.daEditField.Value; % Set based on user setting 
                set_param([app.Trim_mdl(1:end-4) '/DATrim_Sw'],'sw','0');
                set_param([app.Trim_mdl(1:end-4) '/DA_Solve'],'Commented','through');
                set_param([app.Trim_mdl(1:end-4) '/DA_Mem'],'Commented','off');
            end
            if app.qbdotCheckBox.Value == 1 % Trim DE 
                app.trimIn.de_deg = app.deIGEditField.Value; % Establish initial guess
                set_param([app.Trim_mdl(1:end-4) '/DETrim_Sw'],'sw','1');
                set_param([app.Trim_mdl(1:end-4) '/DE_Solve'],'Commented','off');
                set_param([app.Trim_mdl(1:end-4) '/DE_Mem'],'Commented','through');
            else
                app.trimIn.de_deg = app.deEditField.Value; % Set based on user setting 
                set_param([app.Trim_mdl(1:end-4) '/DETrim_Sw'],'sw','0');
                set_param([app.Trim_mdl(1:end-4) '/DE_Solve'],'Commented','through');
                set_param([app.Trim_mdl(1:end-4) '/DE_Mem'],'Commented','off');
            end
            if app.rbdotCheckBox.Value == 1 % Trim DR 
                app.trimIn.dr_deg = app.drIGEditField.Value; % Establish initial guess
                set_param([app.Trim_mdl(1:end-4) '/DRTrim_Sw'],'sw','1');
                set_param([app.Trim_mdl(1:end-4) '/DR_Solve'],'Commented','off');
                set_param([app.Trim_mdl(1:end-4) '/DR_Mem'],'Commented','through');
            else
                app.trimIn.dr_deg = app.drEditField.Value; % Set based on user setting 
                set_param([app.Trim_mdl(1:end-4) '/DRTrim_Sw'],'sw','0');
                set_param([app.Trim_mdl(1:end-4) '/DR_Solve'],'Commented','through');
                set_param([app.Trim_mdl(1:end-4) '/DR_Mem'],'Commented','off');
            end
            % Assign sim parameters to base workspace (needed for model)
            assignin('base','IG_thr',app.trimIn.thr)
            assignin('base','IG_beta',app.trimIn.beta_deg)
            assignin('base','IG_alpha',app.trimIn.alpha_deg)
            assignin('base','IG_da',app.trimIn.da_deg)
            assignin('base','IG_de',app.trimIn.de_deg)
            assignin('base','IG_dr',app.trimIn.dr_deg)
            % Update model
            set_param(app.Trim_mdl(1:end-4),'SimulationCommand','update')
            % Define trim input vector: 
            trimInVec = [0 app.trimIn.altitude_ft app.trimIn.mach app.trimIn.lat_deg app.trimIn.lon_deg...
                         app.trimIn.dlef_deg app.trimIn.bank_deg app.trimIn.gamma_deg app.trimIn.heading_deg...
                         app.trimIn.pb_rps app.trimIn.qb_rps app.trimIn.rb_rps app.trimIn.thr...
                         app.trimIn.beta_deg app.trimIn.alpha_deg app.trimIn.da_deg app.trimIn.de_deg app.trimIn.dr_deg];
            %% Run model 
            warning('off')
            % Update waitbar
            waitbar(0.25,f,'Beginning Trim Routine...');
            try
                simOut = sim(app.Trim_mdl,'','',trimInVec);
                % Various checks to ensure trim solutions are reasonable
                if (simOut.sigsOut.get('thr').Values.Data > 1.2) || (simOut.sigsOut.get('thr').Values.Data < 0)
                    error('Failed Trim Condition')
                end
                if (simOut.sigsOut.get('beta_deg').Values.Data > 25) || (simOut.sigsOut.get('beta_deg').Values.Data < -25)
                    error('Failed Trim Condition')
                end
                if (simOut.sigsOut.get('alpha_deg').Values.Data > 90) || (simOut.sigsOut.get('alpha_deg').Values.Data < -25)
                    error('Failed Trim Condition')
                end
                if (simOut.sigsOut.get('da_deg').Values.Data > 30) || (simOut.sigsOut.get('da_deg').Values.Data < -30)
                    error('Failed Trim Condition')
                end
                if (simOut.sigsOut.get('de_deg').Values.Data > 30) || (simOut.sigsOut.get('de_deg').Values.Data < -30)
                    error('Failed Trim Condition')
                end
                if (simOut.sigsOut.get('dr_deg').Values.Data > 30) || (simOut.sigsOut.get('dr_deg').Values.Data < -30)
                    error('Failed Trim Condition')
                end
                % Successful trim 
                app.SuccessfulTrimLamp.Color = 'green';
                bdclose;
                % Update waitbar
                waitbar(0.5,f,'Trim solution found!');
            catch 
                waitbar(1,f,'FAILED!')
                close(f)
                app.SuccessfulTrimLamp.Color = 'red';
                uialert(app.UIFigure,'A trim parameter exceeds a reasonable valye. Deemed an unsuccessful trim.','Unsuccessful Trim')
                return;
            end

            % Store trim Output data
            app.trimOut = processSimData(app,simOut);

            % Waitbar update 
            waitbar(0.75,f,'Computing Stability Derivatives...')
            % Gather stability settings
            app.trimIn.stability.dthr       = app.dThrottleEditField.Value;
            app.trimIn.stability.dBeta_deg  = app.dBetaEditField.Value;
            app.trimIn.stability.dAlpha_deg = app.dAlphaEditField.Value;
            app.trimIn.stability.dVair      = app.dVairEditField.Value;
            app.trimIn.stability.dp_rps     = app.dpStabEditField.Value;
            app.trimIn.stability.dq_rps     = app.dqStabEditField.Value;
            app.trimIn.stability.dr_rps     = app.drStabEditField.Value;
            app.trimIn.stability.da_deg     = app.dAilEditField.Value;
            app.trimIn.stability.de_deg     = app.dElevEditField.Value;
            app.trimIn.stability.dr_deg     = app.dRudEditField.Value;
            % Linearize about the trim point
            app.trimOut.stability = linearizeFC(app.trimOut,app.trimIn.stability,aeroData,massData);
            % Close waitbar
            waitbar(1,f,'Trim Complete...');
            close(f)

            %% Output trim results to the app window
            % -- Flight Condition
            app.AltOutEditField.Value       = app.trimOut.alt_ft;
            app.MachOutEditField.Value      = app.trimOut.Mach;
            app.VairOutEditField.Value      = app.trimOut.Vair_fps;
            app.qbarOutEditField.Value      = app.trimOut.qbar_psf;
            app.KEASOutEditField.Value      = app.trimOut.KEAS_kts;
            app.ubOutEditField.Value        = app.trimOut.ub_fps;
            app.vbOutEditField.Value        = app.trimOut.vb_fps;
            app.wbOutEditField.Value        = app.trimOut.wb_fps;
            app.AlphaOutEditField.Value     = app.trimOut.alpha_deg;
            app.BetaOutEditField.Value      = app.trimOut.beta_deg;
            app.ThrottleOutEditField.Value  = app.trimOut.thr;
            % -- Vehicle Orientation 
            app.PhiOutEditField.Value       = app.trimOut.phi_deg;
            app.ThetaOutEditField.Value     = app.trimOut.theta_deg;
            app.PsiOutEditField.Value       = app.trimOut.psi_deg;
            app.BankOutEditField.Value      = app.trimOut.bank_deg;
            app.GammaOutEditField.Value     = app.trimOut.gamma_deg;
            app.HeadingOutEditField.Value   = app.trimOut.heading_deg;
            app.HdotOutEditField.Value      = app.trimOut.Vair_fps*sind(app.trimOut.gamma_deg);
            app.PhidotOutEditField.Value    = app.trimOut.phidot_rps;
            app.ThetadotOutEditField.Value  = app.trimOut.thetadot_rps;
            app.PsidotOutEditField.Value    = app.trimOut.psidot_rps;
            % -- Vehicle Body Rates
            app.pbOutEditField.Value        = app.trimOut.pb_rps;
            app.qbOutEditField.Value        = app.trimOut.qb_rps;
            app.rbOutEditField.Value        = app.trimOut.rb_rps;
            % -- Vehicle Body Accelerations
            app.ubdotOutEditField.Value     = app.trimOut.ubdot_fps2;
            app.vbdotOutEditField.Value     = app.trimOut.vbdot_fps2;
            app.wbdotOutEditField.Value     = app.trimOut.wbdot_fps2;
            app.pbdotOutEditField.Value     = app.trimOut.pbdot_rps2;
            app.qbdotOutEditField.Value     = app.trimOut.qbdot_rps2;
            app.rbdotOutEditField.Value     = app.trimOut.rbdot_rps2;
            app.NxOutEditField.Value        = app.trimOut.Nx_g;
            app.NyOutEditField.Value        = app.trimOut.Ny_g;
            app.NzOutEditField.Value        = app.trimOut.Nz_g;
            % -- Environment Data 
            app.tempOutEditField.Value      = app.trimOut.Tt_R;
            app.PairOutEditField.Value      = app.trimOut.Pt_psi;
            app.SoSOutEditField.Value       = app.trimOut.SoS_fps;
            app.rhoOutEditField.Value       = app.trimOut.rho_slft3;
            % -- Control Surfaces
            app.daOutEditField.Value        = app.trimOut.da_deg;
            app.deOutEditField.Value        = app.trimOut.de_deg;
            app.drOutEditField.Value        = app.trimOut.dr_deg;
            app.dlefOutEditField.Value      = app.trimOut.dlef_deg;
            % -- Aerodynamics
            app.XaeroOutEditField.Value     = app.trimOut.Xaero_lbf;
            app.YaeroOutEditField.Value     = app.trimOut.Yaero_lbf;
            app.ZaeroOutEditField.Value     = app.trimOut.Zaero_lbf;
            app.LaeroOutEditField.Value     = app.trimOut.Laero_ftlbf;
            app.MaeroOutEditField.Value     = app.trimOut.Maero_ftlbf;
            app.NaeroOutEditField.Value     = app.trimOut.Naero_ftlbf;
            app.LiftOutEditField.Value      = app.trimOut.lift_aero_lbf;
            app.DragOutEditField.Value      = app.trimOut.drag_aero_lbf;
            app.LoDOutEditField.Value       = app.trimOut.LoD_aero;
            % -- Propulsion
            app.XpropOutEditField.Value     = app.trimOut.Xprop_lbf;
            app.YpropOutEditField.Value     = app.trimOut.Yprop_lbf;
            app.ZpropOutEditField.Value     = app.trimOut.Zprop_lbf;
            app.LpropOutEditField.Value     = app.trimOut.Lprop_ftlbf;
            app.MpropOutEditField.Value     = app.trimOut.Mprop_ftlbf;
            app.NpropOutEditField.Value     = app.trimOut.Nprop_ftlbf;
            app.ThrustOutEditField.Value    = app.trimOut.thrust_lbf;
            app.WdotOutEditField.Value      = app.trimOut.fuelFlow_pps;
            % -- Total Forces and Moments
            app.XtotOutEditField.Value      = app.trimOut.Xtotal_lbf;
            app.YtotOutEditField.Value      = app.trimOut.Ytotal_lbf;
            app.ZtotOutEditField.Value      = app.trimOut.Ztotal_lbf;
            app.LtotOutEditField.Value      = app.trimOut.Ltotal_ftlbf;
            app.MtotOutEditField.Value      = app.trimOut.Mtotal_ftlbf;
            app.NtotOutEditField.Value      = app.trimOut.Ntotal_ftlbf;
            % -- Mass Properties
            app.weightOutEditField.Value    = app.trimOut.weight_lbm;
            app.massOutEditField.Value      = app.trimOut.mass_sl;
            app.xcgOutEditField.Value       = app.trimOut.xcg_ft;
            app.ycgOutEditField.Value       = app.trimOut.ycg_ft;
            app.zcgOutEditField.Value       = app.trimOut.zcg_ft;
            app.ixxOutEditField.Value       = massData.ixx_slft2;
            app.iyyOutEditField.Value       = massData.iyy_slft2;
            app.izzOutEditField.Value       = massData.izz_slft2;
            app.ixyOutEditField.Value       = massData.ixy_slft2;
            app.ixzOutEditField.Value       = massData.ixz_slft2;
            app.iyzOutEditField.Value       = massData.iyz_slft2;
            % ------------------------------------------------------------
            % Non-dimensional Longitudinal Stability Derivatives 
            % -- Alpha 
            app.CLAlphaEditField.Value      = app.trimOut.stability.CL_alpha;
            app.CDAlphaEditField.Value      = app.trimOut.stability.CD_alpha;
            app.CMAlphaEditField.Value      = app.trimOut.stability.Cm_alpha;
            % -- Speed 
            app.CLUEditField.Value          = app.trimOut.stability.CL_U;
            app.CDUEditField.Value          = app.trimOut.stability.CD_U;
            app.CMUEditField.Value          = app.trimOut.stability.Cm_U;
            % -- Pitch Rate
            app.CLqEditField.Value          = app.trimOut.stability.CL_q;
            app.CDqEditField.Value          = app.trimOut.stability.CD_q;
            app.CMqEditField.Value          = app.trimOut.stability.Cm_q;
            % -- Dynamic Derivative
            app.CLAlpdotEditField.Value     = app.trimOut.stability.CL_alpdot;
            app.CDAlpdotEditField.Value     = app.trimOut.stability.CD_alpdot;
            app.CMAlpdotEditField.Value     = app.trimOut.stability.Cm_alpdot;
            % -- Throttle 
            app.CLdTHREditField.Value       = app.trimOut.stability.CL_dthr;
            app.CDdTHREditField.Value       = app.trimOut.stability.CD_dthr;
            app.CMdTHREditField.Value       = app.trimOut.stability.Cm_dthr;
            % -- Ailerion 
            app.CLdAEditField.Value         = app.trimOut.stability.CL_da;
            app.CDdAEditField.Value         = app.trimOut.stability.CD_da;
            app.CMdAEditField.Value         = app.trimOut.stability.Cm_da;
            % -- Elevator
            app.CLdEEditField.Value         = app.trimOut.stability.CL_de;
            app.CDdEEditField.Value         = app.trimOut.stability.CD_de;
            app.CMdEEditField.Value         = app.trimOut.stability.Cm_de;
            % -- Rudder
            app.CLdREditField.Value         = app.trimOut.stability.CL_dr;
            app.CDdREditField.Value         = app.trimOut.stability.CD_dr;
            app.CMdREditField.Value         = app.trimOut.stability.Cm_dr;
            % ------------------------------------------------------------
            % Dimensional Longitudinal Stability Derivatives 
            app.XAlphaEditField.Value       = app.trimOut.stability.dim.X_alpha;
            app.ZAlphaEditField.Value       = app.trimOut.stability.dim.Z_alpha;
            app.MAlphaEditField.Value       = app.trimOut.stability.dim.M_alpha;
            app.XUEditField.Value           = app.trimOut.stability.dim.X_U;
            app.ZUEditField.Value           = app.trimOut.stability.dim.Z_U;
            app.MUEditField.Value           = app.trimOut.stability.dim.M_U;
            app.XqEditField.Value           = app.trimOut.stability.dim.X_q;
            app.ZqEditField.Value           = app.trimOut.stability.dim.Z_q;
            app.MqEditField.Value           = app.trimOut.stability.dim.M_q;
            app.XalpdotEditField.Value      = app.trimOut.stability.dim.X_alpdot;
            app.ZalpdotEditField.Value      = app.trimOut.stability.dim.Z_alpdot;
            app.MalpdotEditField.Value      = app.trimOut.stability.dim.M_alpdot;
            app.XdTHREditField.Value        = app.trimOut.stability.dim.X_dthr;
            app.ZdTHREditField.Value        = app.trimOut.stability.dim.Z_dthr;
            app.MdTHREditField.Value        = app.trimOut.stability.dim.M_dthr;
            app.XdAEditField.Value          = app.trimOut.stability.dim.X_da;
            app.ZdAEditField.Value          = app.trimOut.stability.dim.Z_da;
            app.MdAEditField.Value          = app.trimOut.stability.dim.M_da;
            app.XdEEditField.Value          = app.trimOut.stability.dim.X_de;
            app.ZdEEditField.Value          = app.trimOut.stability.dim.Z_de;
            app.MdEEditField.Value          = app.trimOut.stability.dim.M_de;
            app.XdREditField.Value          = app.trimOut.stability.dim.X_dr;
            app.ZdREditField.Value          = app.trimOut.stability.dim.Z_dr;
            app.MdREditField.Value          = app.trimOut.stability.dim.M_dr;
            % ------------------------------------------------------------
            % Non-dimensional Lat/Dir Stabiity Derivative
            % -- Beta
            app.CnBetaEditField.Value       = app.trimOut.stability.Cn_beta;
            app.CrBetaEditField.Value       = app.trimOut.stability.Cr_beta;
            app.CYBetaEditField.Value       = app.trimOut.stability.CY_beta;
            % -- Roll Rate 
            app.CnpEditField.Value          = app.trimOut.stability.Cn_p;
            app.CrpEditField.Value          = app.trimOut.stability.Cr_p;
            app.CYpEditField.Value          = app.trimOut.stability.CY_p;
            % -- Yaw Rate
            app.CnrEditField.Value          = app.trimOut.stability.Cn_r;
            app.CrrEditField.Value          = app.trimOut.stability.Cr_r;
            app.CYrEditField.Value          = app.trimOut.stability.CY_r;
            % -- Dynamic Derivative
            app.CnBetadotEditField.Value    = app.trimOut.stability.Cn_betdot;
            app.CrBetadotEditField.Value    = app.trimOut.stability.Cr_betdot;
            app.CYBetadotEditField.Value    = app.trimOut.stability.CY_betdot;
            % -- Throttle 
            app.CndTHREditField.Value       = app.trimOut.stability.Cn_dthr;
            app.CrdTHREditField.Value       = app.trimOut.stability.Cr_dthr;
            app.CYdTHREditField.Value       = app.trimOut.stability.CY_dthr;
            % -- Aileron
            app.CndAEditField.Value         = app.trimOut.stability.Cn_da;
            app.CrdAEditField.Value         = app.trimOut.stability.Cr_da;
            app.CYdAEditField.Value         = app.trimOut.stability.CY_da;
            % -- Elevator
            app.CndEEditField.Value         = app.trimOut.stability.Cn_de;
            app.CrdEEditField.Value         = app.trimOut.stability.Cr_de;
            app.CYdEEditField.Value         = app.trimOut.stability.CY_de;
            % -- Rudder 
            app.CndREditField.Value         = app.trimOut.stability.Cn_dr;
            app.CrdREditField.Value         = app.trimOut.stability.Cr_dr;
            app.CYdREditField.Value         = app.trimOut.stability.CY_dr;
            % ------------------------------------------------------------
            % Dimensional Lat/Dir Stability Derivatives
            app.NBetaEditField.Value        = app.trimOut.stability.dim.N_beta;
            app.RBetaEditField.Value        = app.trimOut.stability.dim.R_beta;
            app.YBetaEditField.Value        = app.trimOut.stability.dim.Y_beta;
            app.NpEditField.Value           = app.trimOut.stability.dim.N_p;
            app.RpEditField.Value           = app.trimOut.stability.dim.R_p;
            app.YpEditField.Value           = app.trimOut.stability.dim.Y_p;
            app.NrEditField.Value           = app.trimOut.stability.dim.N_r;
            app.RrEditField.Value           = app.trimOut.stability.dim.R_r;
            app.YrEditField.Value           = app.trimOut.stability.dim.Y_r;
            app.NbetadotEditField.Value     = app.trimOut.stability.dim.N_betdot;
            app.RbetadotEditField.Value     = app.trimOut.stability.dim.R_betdot;
            app.YbetadotEditField.Value     = app.trimOut.stability.dim.Y_betdot;
            app.NdTHREditField.Value        = app.trimOut.stability.dim.N_dthr;
            app.RdTHREditField.Value        = app.trimOut.stability.dim.R_dthr;
            app.YdTHREditField.Value        = app.trimOut.stability.dim.Y_dthr;
            app.NdAEditField.Value          = app.trimOut.stability.dim.N_da;
            app.RdAEditField.Value          = app.trimOut.stability.dim.R_da;
            app.YdAEditField.Value          = app.trimOut.stability.dim.Y_da;
            app.NdEEditField.Value          = app.trimOut.stability.dim.N_de;
            app.RdEEditField.Value          = app.trimOut.stability.dim.R_de;
            app.YdEEditField.Value          = app.trimOut.stability.dim.Y_de;
            app.NdREditField.Value          = app.trimOut.stability.dim.N_dr;
            app.RdREditField.Value          = app.trimOut.stability.dim.R_dr;
            app.YdREditField.Value          = app.trimOut.stability.dim.Y_dr;
            % Primed Dimensional Lat/Dir Stability Derivatives 
            app.NpBetaEditField.Value       = app.trimOut.stability.dim.Np_beta;
            app.RpBetaEditField.Value       = app.trimOut.stability.dim.Rp_beta;
            app.NppEditField.Value          = app.trimOut.stability.dim.Np_p;
            app.RppEditField.Value          = app.trimOut.stability.dim.Rp_p;
            app.NprEditField.Value          = app.trimOut.stability.dim.Np_r;
            app.RprEditField.Value          = app.trimOut.stability.dim.Rp_r;
            app.NpbdotEditField.Value       = app.trimOut.stability.dim.Np_betdot;
            app.RpbdotEditField.Value       = app.trimOut.stability.dim.Rp_betdot;
            app.NpdTHREditField.Value       = app.trimOut.stability.dim.Np_dthr;
            app.RpdTHREditField.Value       = app.trimOut.stability.dim.Rp_dthr;
            app.NpdAEditField.Value         = app.trimOut.stability.dim.Np_da;
            app.RpdAEditField.Value         = app.trimOut.stability.dim.Rp_da;
            app.NpdEEditField.Value         = app.trimOut.stability.dim.Np_de;
            app.RpdEEditField.Value         = app.trimOut.stability.dim.Rp_de;
            app.NpdREditField.Value         = app.trimOut.stability.dim.Np_dr;
            app.RpdREditField.Value         = app.trimOut.stability.dim.Rp_dr;
            %% Longitudinal Linear System 
            % -- A Matrix
            app.Alon11EditField.Value       = app.trimOut.stability.sys.lon.A(1,1);
            app.Alon12EditField.Value       = app.trimOut.stability.sys.lon.A(1,2);
            app.Alon13EditField.Value       = app.trimOut.stability.sys.lon.A(1,3);
            app.Alon14EditField.Value       = app.trimOut.stability.sys.lon.A(1,4);
            app.Alon21EditField.Value       = app.trimOut.stability.sys.lon.A(2,1);
            app.Alon22EditField.Value       = app.trimOut.stability.sys.lon.A(2,2);
            app.Alon23EditField.Value       = app.trimOut.stability.sys.lon.A(2,3);
            app.Alon24EditField.Value       = app.trimOut.stability.sys.lon.A(2,4);
            app.Alon31EditField.Value       = app.trimOut.stability.sys.lon.A(3,1);
            app.Alon32EditField.Value       = app.trimOut.stability.sys.lon.A(3,2);
            app.Alon33EditField.Value       = app.trimOut.stability.sys.lon.A(3,3);
            app.Alon34EditField.Value       = app.trimOut.stability.sys.lon.A(3,4);
            app.Alon41EditField.Value       = app.trimOut.stability.sys.lon.A(4,1);
            app.Alon42EditField.Value       = app.trimOut.stability.sys.lon.A(4,2);
            app.Alon43EditField.Value       = app.trimOut.stability.sys.lon.A(4,3);
            app.Alon44EditField.Value       = app.trimOut.stability.sys.lon.A(4,4);
            % -- B Matrix
            app.Blon11EditField.Value       = app.trimOut.stability.sys.lon.B(1,1);
            app.Blon12EditField.Value       = app.trimOut.stability.sys.lon.B(1,2);
            app.Blon13EditField.Value       = app.trimOut.stability.sys.lon.B(1,3);
            app.Blon14EditField.Value       = app.trimOut.stability.sys.lon.B(1,4);
            app.Blon21EditField.Value       = app.trimOut.stability.sys.lon.B(2,1);
            app.Blon22EditField.Value       = app.trimOut.stability.sys.lon.B(2,2);
            app.Blon23EditField.Value       = app.trimOut.stability.sys.lon.B(2,3);
            app.Blon24EditField.Value       = app.trimOut.stability.sys.lon.B(2,4);
            app.Blon31EditField.Value       = app.trimOut.stability.sys.lon.B(3,1);
            app.Blon32EditField.Value       = app.trimOut.stability.sys.lon.B(3,2);
            app.Blon33EditField.Value       = app.trimOut.stability.sys.lon.B(3,3);
            app.Blon34EditField.Value       = app.trimOut.stability.sys.lon.B(3,4);
            app.Blon41EditField.Value       = app.trimOut.stability.sys.lon.B(4,1);
            app.Blon42EditField.Value       = app.trimOut.stability.sys.lon.B(4,2);
            app.Blon43EditField.Value       = app.trimOut.stability.sys.lon.B(4,3);
            app.Blon44EditField.Value       = app.trimOut.stability.sys.lon.B(4,4);
            % -- Longitudinal Stability Characteristics 
            app.LonEig1EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.eigenvalue(1));
            app.LonEig2EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.eigenvalue(2));
            app.LonEig3EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.eigenvalue(3));
            app.LonEig4EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.eigenvalue(4));
            app.LonT1EditField.Value        = num2str(app.trimOut.stability.sys.lon.chars.T(1));
            app.LonT2EditField.Value        = num2str(app.trimOut.stability.sys.lon.chars.T(2));
            app.LonT3EditField.Value        = num2str(app.trimOut.stability.sys.lon.chars.T(3));
            app.LonT4EditField.Value        = num2str(app.trimOut.stability.sys.lon.chars.T(4));
            app.LonZeta1EditField.Value     = app.trimOut.stability.sys.lon.chars.zeta(1);
            app.LonZeta2EditField.Value     = app.trimOut.stability.sys.lon.chars.zeta(2);
            app.LonZeta3EditField.Value     = app.trimOut.stability.sys.lon.chars.zeta(3);
            app.LonZeta4EditField.Value     = app.trimOut.stability.sys.lon.chars.zeta(4);
            app.LonTTD1EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.time_to_double(1));
            app.LonTTD2EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.time_to_double(2));
            app.LonTTD3EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.time_to_double(3));
            app.LonTTD4EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.time_to_double(4));
            app.LonTau1EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.tau(1));
            app.LonTau2EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.tau(2));
            app.LonTau3EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.tau(3));
            app.LonTau4EditField.Value      = num2str(app.trimOut.stability.sys.lon.chars.tau(4));
            %% Lat/Dir Linear System
            % -- A Matrix
            app.Alat11EditField.Value       = app.trimOut.stability.sys.lat.A(1,1);
            app.Alat12EditField.Value       = app.trimOut.stability.sys.lat.A(1,2);
            app.Alat13EditField.Value       = app.trimOut.stability.sys.lat.A(1,3);
            app.Alat14EditField.Value       = app.trimOut.stability.sys.lat.A(1,4);
            app.Alat21EditField.Value       = app.trimOut.stability.sys.lat.A(2,1);
            app.Alat22EditField.Value       = app.trimOut.stability.sys.lat.A(2,2);
            app.Alat23EditField.Value       = app.trimOut.stability.sys.lat.A(2,3);
            app.Alat24EditField.Value       = app.trimOut.stability.sys.lat.A(2,4);
            app.Alat31EditField.Value       = app.trimOut.stability.sys.lat.A(3,1);
            app.Alat32EditField.Value       = app.trimOut.stability.sys.lat.A(3,2);
            app.Alat33EditField.Value       = app.trimOut.stability.sys.lat.A(3,3);
            app.Alat34EditField.Value       = app.trimOut.stability.sys.lat.A(3,4);
            app.Alat41EditField.Value       = app.trimOut.stability.sys.lat.A(4,1);
            app.Alat42EditField.Value       = app.trimOut.stability.sys.lat.A(4,2);
            app.Alat43EditField.Value       = app.trimOut.stability.sys.lat.A(4,3);
            app.Alat44EditField.Value       = app.trimOut.stability.sys.lat.A(4,4);
            % -- B Matrix
            app.Blat11EditField.Value       = app.trimOut.stability.sys.lat.B(1,1);
            app.Blat12EditField.Value       = app.trimOut.stability.sys.lat.B(1,2);
            app.Blat13EditField.Value       = app.trimOut.stability.sys.lat.B(1,3);
            app.Blat14EditField.Value       = app.trimOut.stability.sys.lat.B(1,4);
            app.Blat21EditField.Value       = app.trimOut.stability.sys.lat.B(2,1);
            app.Blat22EditField.Value       = app.trimOut.stability.sys.lat.B(2,2);
            app.Blat23EditField.Value       = app.trimOut.stability.sys.lat.B(2,3);
            app.Blat24EditField.Value       = app.trimOut.stability.sys.lat.B(2,4);
            app.Blat31EditField.Value       = app.trimOut.stability.sys.lat.B(3,1);
            app.Blat32EditField.Value       = app.trimOut.stability.sys.lat.B(3,2);
            app.Blat33EditField.Value       = app.trimOut.stability.sys.lat.B(3,3);
            app.Blat34EditField.Value       = app.trimOut.stability.sys.lat.B(3,4);
            app.Blat41EditField.Value       = app.trimOut.stability.sys.lat.B(4,1);
            app.Blat42EditField.Value       = app.trimOut.stability.sys.lat.B(4,2);
            app.Blat43EditField.Value       = app.trimOut.stability.sys.lat.B(4,3);
            app.Blat44EditField.Value       = app.trimOut.stability.sys.lat.B(4,4);
            % -- Lat/Dir Stability Characteristics 
            app.LatEig1EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.eigenvalue(1));
            app.LatEig2EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.eigenvalue(2));
            app.LatEig3EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.eigenvalue(3));
            app.LatEig4EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.eigenvalue(4));
            app.LatT1EditField.Value        = num2str(app.trimOut.stability.sys.lat.chars.T(1));
            app.LatT2EditField.Value        = num2str(app.trimOut.stability.sys.lat.chars.T(2));
            app.LatT3EditField.Value        = num2str(app.trimOut.stability.sys.lat.chars.T(3));
            app.LatT4EditField.Value        = num2str(app.trimOut.stability.sys.lat.chars.T(4));
            app.LatZeta1EditField.Value     = app.trimOut.stability.sys.lat.chars.zeta(1);
            app.LatZeta2EditField.Value     = app.trimOut.stability.sys.lat.chars.zeta(2);
            app.LatZeta3EditField.Value     = app.trimOut.stability.sys.lat.chars.zeta(3);
            app.LatZeta4EditField.Value     = app.trimOut.stability.sys.lat.chars.zeta(4);
            app.LatTTD1EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.time_to_double(1));
            app.LatTTD2EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.time_to_double(2));
            app.LatTTD3EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.time_to_double(3));
            app.LatTTD4EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.time_to_double(4));
            app.LatTau1EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.tau(1));
            app.LatTau2EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.tau(2));
            app.LatTau3EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.tau(3));
            app.LatTau4EditField.Value      = num2str(app.trimOut.stability.sys.lat.chars.tau(4));
        end

        % Button pushed function: LoadTrimModelButton
        function LoadTrimModelButtonPushed(app, event)
            % Ask user to select model for Trim Calculation 
            [app.Trim_mdl,app.TrimPath] = uigetfile({'*.slx','*.mdl'},'Select Trim Simulink Model');
            uialert(app.UIFigure,'Trim Model Loaded!',"Proceed with Trim Procedure :)","Icon","info")
        end

        % Value changed function: ubdotCheckBox
        function ubdotCheckBoxValueChanged(app, event)
            ubdot_opt = app.ubdotCheckBox.Value;
            if ubdot_opt
                app.ThrottleEditField.Enable    = 0;
                app.ThrottleIGEditField.Enable  = 1;
                app.ThrottleIGEditField.Value   = app.ThrottleEditField.Value;
            else
                app.ThrottleEditField.Enable    = 1;
                app.ThrottleIGEditField.Enable  = 0;
                app.ThrottleEditField.Value     = app.ThrottleIGEditField.Value;
            end
        end

        % Value changed function: vbdotCheckBox
        function vbdotCheckBoxValueChanged(app, event)
            vbdot_opt = app.vbdotCheckBox.Value;
            if vbdot_opt
                app.BetaEditField.Enable    = 0;
                app.BetaIGEditField.Enable  = 1;
                app.BetaIGEditField.Value   = app.BetaEditField.Value;
            else
                app.BetaEditField.Enable    = 1;
                app.BetaIGEditField.Enable  = 0;
                app.BetaEditField.Value     = app.BetaIGEditField.Value;
            end
        end

        % Value changed function: wbdotCheckBox
        function wbdotCheckBoxValueChanged(app, event)
            wbdot_opt = app.wbdotCheckBox.Value;
            if wbdot_opt
                app.AlphaEditField.Enable    = 0;
                app.AlphaIGEditField.Enable  = 1;
                app.AlphaIGEditField.Value   = app.AlphaEditField.Value;
            else
                app.AlphaEditField.Enable    = 1;
                app.AlphaIGEditField.Enable  = 0;
                app.AlphaEditField.Value     = app.AlphaIGEditField.Value;
            end
        end

        % Value changed function: pbdotCheckBox
        function pbdotCheckBoxValueChanged(app, event)
            pbdot_opt = app.pbdotCheckBox.Value;
            if pbdot_opt
                app.daEditField.Enable    = 0;
                app.daIGEditField.Enable  = 1;
                app.daIGEditField.Value   = app.daEditField.Value;
            else
                app.daEditField.Enable    = 1;
                app.daIGEditField.Enable  = 0;
                app.daEditField.Value     = app.daIGEditField.Value;
            end
        end

        % Value changed function: qbdotCheckBox
        function qbdotCheckBoxValueChanged(app, event)
            qbdot_opt = app.qbdotCheckBox.Value;
            if qbdot_opt
                app.deEditField.Enable    = 0;
                app.deIGEditField.Enable  = 1;
                app.deIGEditField.Value   = app.deEditField.Value;
            else
                app.deEditField.Enable    = 1;
                app.deIGEditField.Enable  = 0;
                app.deEditField.Value     = app.deIGEditField.Value;
            end
        end

        % Value changed function: rbdotCheckBox
        function rbdotCheckBoxValueChanged(app, event)
            rbdot_opt = app.rbdotCheckBox.Value;
            if rbdot_opt
                app.drEditField.Enable    = 0;
                app.drIGEditField.Enable  = 1;
                app.drIGEditField.Value   = app.drEditField.Value;
            else
                app.drEditField.Enable    = 1;
                app.drIGEditField.Enable  = 0;
                app.drEditField.Value     = app.drIGEditField.Value;
            end
        end

        % Button pushed function: SaveTrimStateButton
        function SaveTrimStateButtonPushed(app, event)
            % Get desired save file name from the user
            [desFileName,desLoc] = uiputfile('*.mat');
            % Redefine variables
            inputTrim   = app.trimIn;
            outputTrim  = app.trimOut;
            % Save trim outputs into a .mat file 
            save([desLoc '/' desFileName],'inputTrim','outputTrim');
            uialert(app.UIFigure,['Trim State Saved Successfully!'],'Trim State Saved',"Icon","info")
        end

        % Button pushed function: LoadFCButton
        function LoadFCButtonPushed(app, event)
            % Ask user to select desired trim input 
            [trimInput_file,trimInput_path] = uigetfile({'*.mat'},'Select Trim Input File');
            tmp = load([trimInput_path '/' trimInput_file]);
            % Check desired trim configuration 
            if app.ubdotCheckBox.Value == 1
                app.ThrottleIGEditField.Value   = tmp.outputTrim.thr;
            else
                app.ThrottleEditField.Value     = tmp.outputTrim.thr;
            end
            if app.vbdotCheckBox.Value == 1
                app.BetaIGEditField.Value       = tmp.outputTrim.beta_deg;
            else
                app.BetaEditField.Value         = tmp.outputTrim.beta_deg;
            end
            if app.wbdotCheckBox.Value == 1
                app.AlphaIGEditField.Value      = tmp.outputTrim.alpha_deg;
            else
                app.AlphaEditField.Value        = tmp.outputTrim.alpha_deg;
            end
            if app.pbdotCheckBox.Value == 1
                app.daIGEditField.Value         = tmp.outputTrim.da_deg;
            else
                app.daEditField.Value           = tmp.outputTrim.da_deg;
            end
            if app.qbdotCheckBox.Value == 1
                app.deIGEditField.Value         = tmp.outputTrim.de_deg;
            else
                app.deEditField.Value           = tmp.outputTrim.de_deg;
            end
            if app.rbdotCheckBox.Value == 1
                app.drIGEditField.Value         = tmp.outputTrim.dr_deg;
            else
                app.drEditField.Value           = tmp.outputTrim.dr_deg;
            end
            app.dLEFEditField.Value     = tmp.outputTrim.dlef_deg;
            app.AltEditField.Value      = tmp.outputTrim.alt_ft;
            app.MachEditField.Value     = tmp.outputTrim.Mach;
            app.BankEditField.Value     = tmp.outputTrim.bank_deg;
            app.GammaEditField.Value    = tmp.outputTrim.gamma_deg;
            app.HeadingEditField.Value  = tmp.outputTrim.heading_deg;
            app.PbEditField.Value       = tmp.outputTrim.pb_rps;
            app.QbEditField.Value       = tmp.outputTrim.qb_rps;
            app.RbEditField.Value       = tmp.outputTrim.rb_rps;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TRIMLATLabel = uilabel(app.UIFigure);
            app.TRIMLATLabel.Text = 'TRIMLAT';
            app.TRIMLATSubLabel = uilabel(app.UIFigure);
            app.TRIMLATSubLabel.Text = 'Trim, Response, and Integrated Modeling Linear Analysis Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleTrimTab = uitab(app.TabGroup);
            app.SingleTrimTab.Title = 'Single Trim';
            app.TrimOptionsPanel = uipanel(app.SingleTrimTab);
            app.TrimOptionsPanel.Tooltip = {'Select desired DOF''s for trim. Inputs will be set based on selections'};
            app.TrimOptionsPanel.Title = 'Trim Options';
            app.ubdotCheckBox = uicheckbox(app.TrimOptionsPanel);
            app.ubdotCheckBox.ValueChangedFcn = createCallbackFcn(app, @ubdotCheckBoxValueChanged, true);
            app.ubdotCheckBox.Tooltip = {'X-acceleration trim. Find Throttle position such that ubdot = 0'};
            app.ubdotCheckBox.Text = 'ubdot';
            app.vbdotCheckBox = uicheckbox(app.TrimOptionsPanel);
            app.vbdotCheckBox.ValueChangedFcn = createCallbackFcn(app, @vbdotCheckBoxValueChanged, true);
            app.vbdotCheckBox.Tooltip = {'Y-acceleration trim. Find Beta such that vbdot = 0'};
            app.vbdotCheckBox.Text = 'vbdot';
            app.wbdotCheckBox = uicheckbox(app.TrimOptionsPanel);
            app.wbdotCheckBox.ValueChangedFcn = createCallbackFcn(app, @wbdotCheckBoxValueChanged, true);
            app.wbdotCheckBox.Tooltip = {'Z-acceleration trim. Find Alpha such that wbdot = 0'};
            app.wbdotCheckBox.Text = 'wbdot';
            app.pbdotCheckBox = uicheckbox(app.TrimOptionsPanel);
            app.pbdotCheckBox.ValueChangedFcn = createCallbackFcn(app, @pbdotCheckBoxValueChanged, true);
            app.pbdotCheckBox.Tooltip = {'Roll Acceleration trim. Find Aileron deflection such that pbdot = 0'};
            app.pbdotCheckBox.Text = 'pbdot';
            app.qbdotCheckBox = uicheckbox(app.TrimOptionsPanel);
            app.qbdotCheckBox.ValueChangedFcn = createCallbackFcn(app, @qbdotCheckBoxValueChanged, true);
            app.qbdotCheckBox.Tooltip = {'Pitch Acceleration trim. Find Elevator deflection such that qbdot = 0'};
            app.qbdotCheckBox.Text = 'qbdot';
            app.rbdotCheckBox = uicheckbox(app.TrimOptionsPanel);
            app.rbdotCheckBox.ValueChangedFcn = createCallbackFcn(app, @rbdotCheckBoxValueChanged, true);
            app.rbdotCheckBox.Tooltip = {'Yaw Acceleration trim. Find Rudder deflection such that rbdot = 0'};
            app.rbdotCheckBox.Text = 'rbdot';
            app.GearModeButtonGroup = uibuttongroup(app.SingleTrimTab);
            app.GearModeButtonGroup.Tooltip = {'Select state of landing gear. Note there is no current functionality for this button'};
            app.GearModeButtonGroup.Title = 'Gear Mode';
            app.UpButton = uiradiobutton(app.GearModeButtonGroup);
            app.UpButton.Text = 'Up';
            app.UpButton.Value = true;
            app.DownButton = uiradiobutton(app.GearModeButtonGroup);
            app.DownButton.Text = 'Down';
            app.PayloadStateButtonGroup = uibuttongroup(app.SingleTrimTab);
            app.PayloadStateButtonGroup.Tooltip = {'Select payload state. Note there is no current functionality for this button'};
            app.PayloadStateButtonGroup.Title = 'Payload State';
            app.StowedButton = uiradiobutton(app.PayloadStateButtonGroup);
            app.StowedButton.Text = 'Stowed';
            app.StowedButton.Value = true;
            app.DeployedButton = uiradiobutton(app.PayloadStateButtonGroup);
            app.DeployedButton.Text = 'Deployed';
            app.InputsTabGroup = uitabgroup(app.SingleTrimTab);
            app.TrimInputsTab = uitab(app.InputsTabGroup);
            app.TrimInputsTab.Title = 'Trim Inputs';
            app.ThrottleLabel = uilabel(app.TrimInputsTab);
            app.ThrottleLabel.Text = 'Throttle';
            app.ThrottleEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.ThrottleEditField.Limits = [0 1];
            app.ThrottleEditField.Tooltip = {'Enter desired Throttle setting from 0 to 1'};
            app.FixedLabel = uilabel(app.TrimInputsTab);
            app.FixedLabel.Text = 'Fixed';
            app.InitialGuessLabel = uilabel(app.TrimInputsTab);
            app.InitialGuessLabel.Text = 'Initial Guess';
            app.ThrottleIGEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.ThrottleIGEditField.Limits = [0 1];
            app.ThrottleIGEditField.Enable = 'off';
            app.ThrottleIGEditField.Tooltip = {'Enter initial guess for trimmed throttle'};
            app.BetadegEditFieldLabel = uilabel(app.TrimInputsTab);
            app.BetadegEditFieldLabel.Text = 'Beta (deg)';
            app.BetaEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.BetaEditField.Limits = [-50 50];
            app.BetaEditField.Tooltip = {'Enter desired Sideslip angle in deg'};
            app.BetaIGEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.BetaIGEditField.Limits = [-50 50];
            app.BetaIGEditField.Enable = 'off';
            app.BetaIGEditField.Tooltip = {'Enter initial guess for trimmed beta'};
            app.AlphadegLabel = uilabel(app.TrimInputsTab);
            app.AlphadegLabel.Text = 'Alpha (deg)';
            app.AlphaEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.AlphaEditField.Limits = [-50 50];
            app.AlphaEditField.Tooltip = {'Enter desired Angle of attack in deg'};
            app.AlphaIGEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.AlphaIGEditField.Limits = [-50 50];
            app.AlphaIGEditField.Enable = 'off';
            app.AlphaIGEditField.Tooltip = {'Enter initial guess for trimmed Alpha'};
            app.AlphadegLabel_2 = uilabel(app.TrimInputsTab);
            app.AlphadegLabel_2.Text = 'dA (deg)';
            app.daEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.daEditField.Limits = [-25 25];
            app.daEditField.Tooltip = {'Enter desired Aileron deflection in deg'};
            app.daIGEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.daIGEditField.Limits = [-25 25];
            app.daIGEditField.Enable = 'off';
            app.daIGEditField.Tooltip = {'Enter initial guess for trimmed Aileron deflection'};
            app.AlphadegLabel_3 = uilabel(app.TrimInputsTab);
            app.AlphadegLabel_3.Text = 'dE (deg)';
            app.deEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.deEditField.Limits = [-25 25];
            app.deEditField.Tooltip = {'Enter desired Elevator deflection in deg'};
            app.deIGEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.deIGEditField.Limits = [-25 25];
            app.deIGEditField.Enable = 'off';
            app.deIGEditField.Tooltip = {'Enter initial guess for trimmed elevator deflection'};
            app.AlphadegLabel_4 = uilabel(app.TrimInputsTab);
            app.AlphadegLabel_4.Text = 'dR (deg)';
            app.drEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.drEditField.Limits = [-30 30];
            app.drEditField.Tooltip = {'Enter desired Rudder deflection in deg'};
            app.drIGEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.drIGEditField.Limits = [-30 30];
            app.drIGEditField.Enable = 'off';
            app.drIGEditField.Tooltip = {'Enter initial guess for trimmed rudder deflection'};
            app.AlphadegLabel_5 = uilabel(app.TrimInputsTab);
            app.AlphadegLabel_5.Text = 'Altitude (ft)';
            app.AltEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.AltEditField.Limits = [0 60000];
            app.AltEditField.Tooltip = {'Enter desired altitude in ft'};
            app.AlphadegLabel_6 = uilabel(app.TrimInputsTab);
            app.AlphadegLabel_6.Text = 'Mach';
            app.MachEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.MachEditField.Limits = [0 3];
            app.MachEditField.Tooltip = {'Enter desired Mach number'};
            app.BankdegEditFieldLabel = uilabel(app.TrimInputsTab);
            app.BankdegEditFieldLabel.Text = 'Bank (deg)';
            app.BankEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.BankEditField.Tooltip = {'Enter desired Bank angle in deg'};
            app.GammadegLabel = uilabel(app.TrimInputsTab);
            app.GammadegLabel.Text = 'Gamma (deg)';
            app.GammaEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.GammaEditField.Tooltip = {'Enter desired Flight Path angle in deg'};
            app.GammadegLabel_2 = uilabel(app.TrimInputsTab);
            app.GammadegLabel_2.Text = 'Heading (deg)';
            app.HeadingEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.HeadingEditField.Tooltip = {'Enter desired Heading angle in deg'};
            app.GammadegLabel_4 = uilabel(app.TrimInputsTab);
            app.GammadegLabel_4.Text = 'Weight Bias';
            app.WeightBiasEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.WeightBiasEditField.Tooltip = {'Enter weight bias (+/-)'};
            app.XcgBiasftEditFieldLabel = uilabel(app.TrimInputsTab);
            app.XcgBiasftEditFieldLabel.Text = 'X-cg Bias (ft)';
            app.XcgBiasEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.XcgBiasEditField.Tooltip = {'Enter X-cg bias (+/-)'};
            app.YcgBiasftLabel = uilabel(app.TrimInputsTab);
            app.YcgBiasftLabel.Text = 'Y-cg Bias (ft)';
            app.YcgBiasEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.YcgBiasEditField.Tooltip = {'Enter Y-cg bias (+/-)'};
            app.YcgBiasftLabel_2 = uilabel(app.TrimInputsTab);
            app.YcgBiasftLabel_2.Text = 'Z-cg Bias (ft)';
            app.ZcgBiasEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.ZcgBiasEditField.Tooltip = {'Enter Z-cg bias (+/-)'};
            app.AlphadegLabel_7 = uilabel(app.TrimInputsTab);
            app.AlphadegLabel_7.Text = 'dLEF (deg)';
            app.dLEFEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.dLEFEditField.Limits = [-30 30];
            app.dLEFEditField.Tooltip = {'Enter desired Leading Edge Flap deflection in deg'};
            app.GammadegLabel_5 = uilabel(app.TrimInputsTab);
            app.GammadegLabel_5.Text = 'P (rps)';
            app.PbEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.PbEditField.Tooltip = {'Enter initial roll rate in body frame in rps'};
            app.GammadegLabel_6 = uilabel(app.TrimInputsTab);
            app.GammadegLabel_6.Text = 'Q (rps)';
            app.QbEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.QbEditField.Tooltip = {'Enter initial pitch rate in body frame in rps'};
            app.GammadegLabel_7 = uilabel(app.TrimInputsTab);
            app.GammadegLabel_7.Text = 'R (rps)';
            app.RbEditField = uieditfield(app.TrimInputsTab, 'numeric');
            app.RbEditField.Tooltip = {'Enter initial yaw rate in body frame in rps'};
            app.StabilitySettingsTab = uitab(app.InputsTabGroup);
            app.StabilitySettingsTab.Title = 'Stability Settings';
            app.dThrottleLabel = uilabel(app.StabilitySettingsTab);
            app.dThrottleLabel.Text = 'dThrottle';
            app.dThrottleEditField = uieditfield(app.StabilitySettingsTab, 'numeric');
            app.dThrottleEditField.Tooltip = {'Engine Throttle perturbation in %'};
            app.dThrottleEditField.Value = 0.1;
            app.dBetadegEditFieldLabel = uilabel(app.StabilitySettingsTab);
            app.dBetadegEditFieldLabel.Text = 'dBeta (deg)';
            app.dBetaEditField = uieditfield(app.StabilitySettingsTab, 'numeric');
            app.dBetaEditField.Tooltip = {'Sideslip angle perturbation in degrees'};
            app.dBetaEditField.Value = 0.1;
            app.dAlphadegEditFieldLabel = uilabel(app.StabilitySettingsTab);
            app.dAlphadegEditFieldLabel.Text = 'dAlpha (deg)';
            app.dAlphaEditField = uieditfield(app.StabilitySettingsTab, 'numeric');
            app.dAlphaEditField.Tooltip = {'Angle of attack perturbation in degrees'};
            app.dAlphaEditField.Value = 0.1;
            app.dVairfpsLabel = uilabel(app.StabilitySettingsTab);
            app.dVairfpsLabel.Text = 'dVair (fps)';
            app.dVairEditField = uieditfield(app.StabilitySettingsTab, 'numeric');
            app.dVairEditField.Tooltip = {'Freestream velocity perturbation in ft/s'};
            app.dVairEditField.Value = 5;
            app.dVairfpsLabel_2 = uilabel(app.StabilitySettingsTab);
            app.dVairfpsLabel_2.Text = 'dp (rps)';
            app.dpStabEditField = uieditfield(app.StabilitySettingsTab, 'numeric');
            app.dpStabEditField.Tooltip = {'Roll rate perturbation in rads per second'};
            app.dpStabEditField.Value = 1;
            app.dVairfpsLabel_3 = uilabel(app.StabilitySettingsTab);
            app.dVairfpsLabel_3.Text = 'dq (rps)';
            app.dqStabEditField = uieditfield(app.StabilitySettingsTab, 'numeric');
            app.dqStabEditField.Tooltip = {'Pitch rate perturbation in rads per second'};
            app.dqStabEditField.Value = 1;
            app.dVairfpsLabel_4 = uilabel(app.StabilitySettingsTab);
            app.dVairfpsLabel_4.Text = 'dr (rps)';
            app.drStabEditField = uieditfield(app.StabilitySettingsTab, 'numeric');
            app.drStabEditField.Tooltip = {'Yaw rate perturbation in rads per second'};
            app.drStabEditField.Value = 1;
            app.dVairfpsLabel_5 = uilabel(app.StabilitySettingsTab);
            app.dVairfpsLabel_5.Text = 'dAil (deg)';
            app.dAilEditField = uieditfield(app.StabilitySettingsTab, 'numeric');
            app.dAilEditField.Tooltip = {'Aileron deflection perturbation in degrees'};
            app.dAilEditField.Value = 1;
            app.dVairfpsLabel_6 = uilabel(app.StabilitySettingsTab);
            app.dVairfpsLabel_6.Text = 'dElev (deg)';
            app.dElevEditField = uieditfield(app.StabilitySettingsTab, 'numeric');
            app.dElevEditField.Tooltip = {'Elevator deflection perturbation in degrees'};
            app.dElevEditField.Value = 1;
            app.dVairfpsLabel_7 = uilabel(app.StabilitySettingsTab);
            app.dVairfpsLabel_7.Text = 'dRud (deg)';
            app.dRudEditField = uieditfield(app.StabilitySettingsTab, 'numeric');
            app.dRudEditField.Tooltip = {'Elevator deflection perturbation in degrees'};
            app.dRudEditField.Value = 1;
            app.OutputsTabGroup = uitabgroup(app.SingleTrimTab);
            app.TrimOutputsTab = uitab(app.OutputsTabGroup);
            app.TrimOutputsTab.Title = 'Trim Outputs';
            app.FlightConditionPanel = uipanel(app.TrimOutputsTab);
            app.FlightConditionPanel.Title = 'Flight Condition';
            app.AlphadegLabel_8 = uilabel(app.FlightConditionPanel);
            app.AlphadegLabel_8.Text = 'Altitude (ft)';
            app.AltOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.AltOutEditField.Tooltip = {'Altitude defined by input'};
            app.MachEditField_2Label = uilabel(app.FlightConditionPanel);
            app.MachEditField_2Label.Text = 'Mach';
            app.MachOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.MachOutEditField.Tooltip = {'Mach number defined by input'};
            app.MachEditField_2Label_3 = uilabel(app.FlightConditionPanel);
            app.MachEditField_2Label_3.Text = 'Vair (fps)';
            app.VairOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.VairOutEditField.Tooltip = {'Calculated airspeed'};
            app.MachEditField_2Label_4 = uilabel(app.FlightConditionPanel);
            app.MachEditField_2Label_4.Text = 'ub (fps)';
            app.ubOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.ubOutEditField.Tooltip = {'x-component of velocity in body frame'};
            app.MachEditField_2Label_5 = uilabel(app.FlightConditionPanel);
            app.MachEditField_2Label_5.Text = 'vb (fps)';
            app.vbOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.vbOutEditField.Tooltip = {'y-component of velocity in body frame'};
            app.MachEditField_2Label_6 = uilabel(app.FlightConditionPanel);
            app.MachEditField_2Label_6.Text = 'wb (fps)';
            app.wbOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.wbOutEditField.Tooltip = {'z-component of velocity in body frame'};
            app.MachEditField_2Label_7 = uilabel(app.FlightConditionPanel);
            app.MachEditField_2Label_7.Text = 'Alpha (deg)';
            app.AlphaOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.AlphaOutEditField.Tooltip = {'Trim Angle of Attack'};
            app.MachEditField_2Label_8 = uilabel(app.FlightConditionPanel);
            app.MachEditField_2Label_8.Text = 'Beta (deg)';
            app.BetaOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.BetaOutEditField.Tooltip = {'Trim angle of sideslip'};
            app.MachEditField_2Label_9 = uilabel(app.FlightConditionPanel);
            app.MachEditField_2Label_9.Text = 'Throttle';
            app.ThrottleOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.ThrottleOutEditField.Tooltip = {'Trim throttle position'};
            app.MachEditField_2Label_2 = uilabel(app.FlightConditionPanel);
            app.MachEditField_2Label_2.Text = 'Qbar (psf)';
            app.qbarOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.qbarOutEditField.Tooltip = {'Calculated dynamic pressure'};
            app.AlphadegLabel_71 = uilabel(app.FlightConditionPanel);
            app.AlphadegLabel_71.Text = 'KEAS';
            app.KEASOutEditField = uieditfield(app.FlightConditionPanel, 'numeric');
            app.KEASOutEditField.Tooltip = {'Knots equivalent airspeed'};
            app.VehicleOrientationPanel = uipanel(app.TrimOutputsTab);
            app.VehicleOrientationPanel.Title = 'Vehicle Orientation';
            app.AlphadegLabel_9 = uilabel(app.VehicleOrientationPanel);
            app.AlphadegLabel_9.Text = 'Phi (deg)';
            app.PhiOutEditField = uieditfield(app.VehicleOrientationPanel, 'numeric');
            app.PhiOutEditField.Tooltip = {''};
            app.AlphadegLabel_10 = uilabel(app.VehicleOrientationPanel);
            app.AlphadegLabel_10.Text = 'Theta (deg)';
            app.ThetaOutEditField = uieditfield(app.VehicleOrientationPanel, 'numeric');
            app.ThetaOutEditField.Tooltip = {''};
            app.AlphadegLabel_11 = uilabel(app.VehicleOrientationPanel);
            app.AlphadegLabel_11.Text = 'Psi (deg)';
            app.PsiOutEditField = uieditfield(app.VehicleOrientationPanel, 'numeric');
            app.PsiOutEditField.Tooltip = {''};
            app.AlphadegLabel_12 = uilabel(app.VehicleOrientationPanel);
            app.AlphadegLabel_12.Text = 'Bank (deg)';
            app.BankOutEditField = uieditfield(app.VehicleOrientationPanel, 'numeric');
            app.BankOutEditField.Tooltip = {''};
            app.AlphadegLabel_13 = uilabel(app.VehicleOrientationPanel);
            app.AlphadegLabel_13.Text = 'Gamma (deg)';
            app.GammaOutEditField = uieditfield(app.VehicleOrientationPanel, 'numeric');
            app.GammaOutEditField.Tooltip = {''};
            app.AlphadegLabel_14 = uilabel(app.VehicleOrientationPanel);
            app.AlphadegLabel_14.Text = 'Heading (deg)';
            app.HeadingOutEditField = uieditfield(app.VehicleOrientationPanel, 'numeric');
            app.HeadingOutEditField.Tooltip = {''};
            app.AlphadegLabel_15 = uilabel(app.VehicleOrientationPanel);
            app.AlphadegLabel_15.Text = 'Hdot (fps)';
            app.HdotOutEditField = uieditfield(app.VehicleOrientationPanel, 'numeric');
            app.HdotOutEditField.Tooltip = {'Vehicle climb rate'};
            app.AlphadegLabel_75 = uilabel(app.VehicleOrientationPanel);
            app.AlphadegLabel_75.Text = 'Phidot (rps)';
            app.PhidotOutEditField = uieditfield(app.VehicleOrientationPanel, 'numeric');
            app.PhidotOutEditField.Tooltip = {''};
            app.AlphadegLabel_76 = uilabel(app.VehicleOrientationPanel);
            app.AlphadegLabel_76.Text = 'Thetadot (rps)';
            app.ThetadotOutEditField = uieditfield(app.VehicleOrientationPanel, 'numeric');
            app.ThetadotOutEditField.Tooltip = {''};
            app.AlphadegLabel_77 = uilabel(app.VehicleOrientationPanel);
            app.AlphadegLabel_77.Text = 'Psidot (rps)';
            app.PsidotOutEditField = uieditfield(app.VehicleOrientationPanel, 'numeric');
            app.PsidotOutEditField.Tooltip = {''};
            app.VehicleBodyRatesPanel = uipanel(app.TrimOutputsTab);
            app.VehicleBodyRatesPanel.Title = 'Vehicle Body Rates';
            app.AlphadegLabel_16 = uilabel(app.VehicleBodyRatesPanel);
            app.AlphadegLabel_16.Text = 'pb (rps)';
            app.pbOutEditField = uieditfield(app.VehicleBodyRatesPanel, 'numeric');
            app.pbOutEditField.Tooltip = {'Body roll rate'};
            app.AlphadegLabel_17 = uilabel(app.VehicleBodyRatesPanel);
            app.AlphadegLabel_17.Text = 'qb (rps)';
            app.qbOutEditField = uieditfield(app.VehicleBodyRatesPanel, 'numeric');
            app.qbOutEditField.Tooltip = {'Body pitch rate'};
            app.AlphadegLabel_18 = uilabel(app.VehicleBodyRatesPanel);
            app.AlphadegLabel_18.Text = 'rb (rps)';
            app.rbOutEditField = uieditfield(app.VehicleBodyRatesPanel, 'numeric');
            app.rbOutEditField.Tooltip = {'Body yaw rate'};
            app.VehicleBodyAccelerationsPanel = uipanel(app.TrimOutputsTab);
            app.VehicleBodyAccelerationsPanel.Title = 'Vehicle Body Accelerations';
            app.AlphadegLabel_19 = uilabel(app.VehicleBodyAccelerationsPanel);
            app.AlphadegLabel_19.Text = 'ubdot (fps2)';
            app.ubdotOutEditField = uieditfield(app.VehicleBodyAccelerationsPanel, 'numeric');
            app.ubdotOutEditField.Tooltip = {'x-component of acceleration in the body frame'};
            app.AlphadegLabel_20 = uilabel(app.VehicleBodyAccelerationsPanel);
            app.AlphadegLabel_20.Text = 'vbdot (fps2)';
            app.vbdotOutEditField = uieditfield(app.VehicleBodyAccelerationsPanel, 'numeric');
            app.vbdotOutEditField.Tooltip = {'y-component of acceleration in the body frame'};
            app.AlphadegLabel_21 = uilabel(app.VehicleBodyAccelerationsPanel);
            app.AlphadegLabel_21.Text = 'wbdot (fps2)';
            app.wbdotOutEditField = uieditfield(app.VehicleBodyAccelerationsPanel, 'numeric');
            app.wbdotOutEditField.Tooltip = {'z-component of acceleration in the body frame'};
            app.AlphadegLabel_22 = uilabel(app.VehicleBodyAccelerationsPanel);
            app.AlphadegLabel_22.Text = 'pbdot (rps2)';
            app.pbdotOutEditField = uieditfield(app.VehicleBodyAccelerationsPanel, 'numeric');
            app.pbdotOutEditField.Tooltip = {'Body roll acceleration'};
            app.AlphadegLabel_23 = uilabel(app.VehicleBodyAccelerationsPanel);
            app.AlphadegLabel_23.Text = 'qbdot (rps2)';
            app.qbdotOutEditField = uieditfield(app.VehicleBodyAccelerationsPanel, 'numeric');
            app.qbdotOutEditField.Tooltip = {'Body pitch acceleration'};
            app.AlphadegLabel_24 = uilabel(app.VehicleBodyAccelerationsPanel);
            app.AlphadegLabel_24.Text = 'rbdot (rps2)';
            app.rbdotOutEditField = uieditfield(app.VehicleBodyAccelerationsPanel, 'numeric');
            app.rbdotOutEditField.Tooltip = {'Body yaw acceleration'};
            app.AlphadegLabel_25 = uilabel(app.VehicleBodyAccelerationsPanel);
            app.AlphadegLabel_25.Text = 'Nx (g''s)';
            app.NxOutEditField = uieditfield(app.VehicleBodyAccelerationsPanel, 'numeric');
            app.NxOutEditField.Tooltip = {'Body x-acceleration in g''s'};
            app.AlphadegLabel_26 = uilabel(app.VehicleBodyAccelerationsPanel);
            app.AlphadegLabel_26.Text = 'Ny (g''s)';
            app.NyOutEditField = uieditfield(app.VehicleBodyAccelerationsPanel, 'numeric');
            app.NyOutEditField.Tooltip = {'Body y-acceleration in g''s'};
            app.AlphadegLabel_27 = uilabel(app.VehicleBodyAccelerationsPanel);
            app.AlphadegLabel_27.Text = 'Nz (g''s)';
            app.NzOutEditField = uieditfield(app.VehicleBodyAccelerationsPanel, 'numeric');
            app.NzOutEditField.Tooltip = {'Body z-acceleration in g''s'};
            app.AerodynamicsPanel = uipanel(app.TrimOutputsTab);
            app.AerodynamicsPanel.Title = 'Aerodynamics';
            app.AlphadegLabel_28 = uilabel(app.AerodynamicsPanel);
            app.AlphadegLabel_28.Text = 'Xaero (lbf)';
            app.XaeroOutEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.XaeroOutEditField.Tooltip = {'Aerodynamic X-force in body frame'};
            app.AlphadegLabel_29 = uilabel(app.AerodynamicsPanel);
            app.AlphadegLabel_29.Text = 'Yaero (lbf)';
            app.YaeroOutEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.YaeroOutEditField.Tooltip = {'Aerodynamic Y-force in body frame'};
            app.AlphadegLabel_30 = uilabel(app.AerodynamicsPanel);
            app.AlphadegLabel_30.Text = 'Zaero (lbf)';
            app.ZaeroOutEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.ZaeroOutEditField.Tooltip = {'Aerodynamic Z-force in body frame'};
            app.AlphadegLabel_31 = uilabel(app.AerodynamicsPanel);
            app.AlphadegLabel_31.Text = 'Laero (ft-lbf)';
            app.LaeroOutEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.LaeroOutEditField.Tooltip = {'Aerodynamic roll moment about CG'};
            app.AlphadegLabel_32 = uilabel(app.AerodynamicsPanel);
            app.AlphadegLabel_32.Text = 'Maero (ft-lbf)';
            app.MaeroOutEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.MaeroOutEditField.Tooltip = {'Aerodynamic pitch moment about CG'};
            app.AlphadegLabel_33 = uilabel(app.AerodynamicsPanel);
            app.AlphadegLabel_33.Text = 'Naero (ft-lbf)';
            app.NaeroOutEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.NaeroOutEditField.Tooltip = {'Aerodynamic yaw moment about CG'};
            app.AlphadegLabel_34 = uilabel(app.AerodynamicsPanel);
            app.AlphadegLabel_34.Text = 'Lift (lbf)';
            app.LiftOutEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.LiftOutEditField.Tooltip = {'Vehicle lift in stability frame'};
            app.AlphadegLabel_35 = uilabel(app.AerodynamicsPanel);
            app.AlphadegLabel_35.Text = 'Drag (lbf)';
            app.DragOutEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.DragOutEditField.Tooltip = {'Vehicle drag in stability frame'};
            app.AlphadegLabel_36 = uilabel(app.AerodynamicsPanel);
            app.AlphadegLabel_36.Text = 'L/D';
            app.LoDOutEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.LoDOutEditField.Tooltip = {'Lift to Drag ratio'};
            app.PropulsionPanel = uipanel(app.TrimOutputsTab);
            app.PropulsionPanel.Title = 'Propulsion';
            app.AlphadegLabel_40 = uilabel(app.PropulsionPanel);
            app.AlphadegLabel_40.Text = 'Xprop (lbf)';
            app.XpropOutEditField = uieditfield(app.PropulsionPanel, 'numeric');
            app.XpropOutEditField.Tooltip = {'Propulsive X-force in the body frame'};
            app.AlphadegLabel_41 = uilabel(app.PropulsionPanel);
            app.AlphadegLabel_41.Text = 'Yprop (lbf)';
            app.YpropOutEditField = uieditfield(app.PropulsionPanel, 'numeric');
            app.YpropOutEditField.Tooltip = {'Propulsive Y-force in the body frame'};
            app.AlphadegLabel_42 = uilabel(app.PropulsionPanel);
            app.AlphadegLabel_42.Text = 'Zprop (lbf)';
            app.ZpropOutEditField = uieditfield(app.PropulsionPanel, 'numeric');
            app.ZpropOutEditField.Tooltip = {'Propulsive Z-force in the body frame'};
            app.AlphadegLabel_43 = uilabel(app.PropulsionPanel);
            app.AlphadegLabel_43.Text = 'Lprop (ft-lbf)';
            app.LpropOutEditField = uieditfield(app.PropulsionPanel, 'numeric');
            app.LpropOutEditField.Tooltip = {'Propulsive roll moment about the CG'};
            app.AlphadegLabel_44 = uilabel(app.PropulsionPanel);
            app.AlphadegLabel_44.Text = 'Mprop (ft-lbf)';
            app.MpropOutEditField = uieditfield(app.PropulsionPanel, 'numeric');
            app.MpropOutEditField.Tooltip = {'Propulsive pitch moment about the CG'};
            app.AlphadegLabel_45 = uilabel(app.PropulsionPanel);
            app.AlphadegLabel_45.Text = 'Nprop (ft-lbf)';
            app.NpropOutEditField = uieditfield(app.PropulsionPanel, 'numeric');
            app.NpropOutEditField.Tooltip = {'Propulsive yaw moment about the CG'};
            app.AlphadegLabel_46 = uilabel(app.PropulsionPanel);
            app.AlphadegLabel_46.Text = 'Thrust (lbf)';
            app.ThrustOutEditField = uieditfield(app.PropulsionPanel, 'numeric');
            app.ThrustOutEditField.Tooltip = {'Propulsive Thrust'};
            app.AlphadegLabel_47 = uilabel(app.PropulsionPanel);
            app.AlphadegLabel_47.Text = 'Fuel Flow (lbm/s)';
            app.WdotOutEditField = uieditfield(app.PropulsionPanel, 'numeric');
            app.WdotOutEditField.Tooltip = {'Fuel flow rate of engine '};
            app.ControlSurfacesPanel = uipanel(app.TrimOutputsTab);
            app.ControlSurfacesPanel.Title = 'Control Surfaces';
            app.AlphadegLabel_48 = uilabel(app.ControlSurfacesPanel);
            app.AlphadegLabel_48.Text = 'DA (deg)';
            app.daOutEditField = uieditfield(app.ControlSurfacesPanel, 'numeric');
            app.daOutEditField.Tooltip = {'Trim aileron deflection'};
            app.AlphadegLabel_49 = uilabel(app.ControlSurfacesPanel);
            app.AlphadegLabel_49.Text = 'DE (deg)';
            app.deOutEditField = uieditfield(app.ControlSurfacesPanel, 'numeric');
            app.deOutEditField.Tooltip = {'Trim elevator deflection'};
            app.AlphadegLabel_50 = uilabel(app.ControlSurfacesPanel);
            app.AlphadegLabel_50.Text = 'DR (deg)';
            app.drOutEditField = uieditfield(app.ControlSurfacesPanel, 'numeric');
            app.drOutEditField.Tooltip = {'Trim rudder deflection'};
            app.AlphadegLabel_51 = uilabel(app.ControlSurfacesPanel);
            app.AlphadegLabel_51.Text = 'DLEF (deg)';
            app.dlefOutEditField = uieditfield(app.ControlSurfacesPanel, 'numeric');
            app.dlefOutEditField.Tooltip = {'Leading edge flap deflection '};
            app.TotalForcesandMomentsPanel = uipanel(app.TrimOutputsTab);
            app.TotalForcesandMomentsPanel.Title = 'Total Forces and Moments';
            app.AlphadegLabel_52 = uilabel(app.TotalForcesandMomentsPanel);
            app.AlphadegLabel_52.Text = 'X-Force (lbf)';
            app.XtotOutEditField = uieditfield(app.TotalForcesandMomentsPanel, 'numeric');
            app.XtotOutEditField.Tooltip = {'Total vehicle X-force in the body frame'};
            app.AlphadegLabel_53 = uilabel(app.TotalForcesandMomentsPanel);
            app.AlphadegLabel_53.Text = 'Y-Force (lbf)';
            app.YtotOutEditField = uieditfield(app.TotalForcesandMomentsPanel, 'numeric');
            app.YtotOutEditField.Tooltip = {'Total vehicle Y-force in the body frame'};
            app.AlphadegLabel_54 = uilabel(app.TotalForcesandMomentsPanel);
            app.AlphadegLabel_54.Text = 'Z-Force (lbf)';
            app.ZtotOutEditField = uieditfield(app.TotalForcesandMomentsPanel, 'numeric');
            app.ZtotOutEditField.Tooltip = {'Total vehicle Z-force in the body frame'};
            app.AlphadegLabel_55 = uilabel(app.TotalForcesandMomentsPanel);
            app.AlphadegLabel_55.Text = 'L (ft-lbf)';
            app.LtotOutEditField = uieditfield(app.TotalForcesandMomentsPanel, 'numeric');
            app.LtotOutEditField.Tooltip = {'Total vehicle roll moment about the CG'};
            app.AlphadegLabel_56 = uilabel(app.TotalForcesandMomentsPanel);
            app.AlphadegLabel_56.Text = 'M (ft-lbf)';
            app.MtotOutEditField = uieditfield(app.TotalForcesandMomentsPanel, 'numeric');
            app.MtotOutEditField.Tooltip = {'Total vehicle pitch moment about the CG'};
            app.AlphadegLabel_57 = uilabel(app.TotalForcesandMomentsPanel);
            app.AlphadegLabel_57.Text = 'N (ft-lbf)';
            app.NtotOutEditField = uieditfield(app.TotalForcesandMomentsPanel, 'numeric');
            app.NtotOutEditField.Tooltip = {'Total vehicle yaw moment about the CG'};
            app.MassPropertiesPanel = uipanel(app.TrimOutputsTab);
            app.MassPropertiesPanel.Title = 'Mass Properties';
            app.AlphadegLabel_58 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_58.Text = 'Weight (lbm)';
            app.weightOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.weightOutEditField.Tooltip = {'Vehicle weight'};
            app.AlphadegLabel_60 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_60.Text = 'X-CG (ft)';
            app.xcgOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.xcgOutEditField.Tooltip = {'Vehicle X-CG location'};
            app.AlphadegLabel_61 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_61.Text = 'Y-CG (ft)';
            app.ycgOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.ycgOutEditField.Tooltip = {'Vehicle Y-CG location'};
            app.AlphadegLabel_62 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_62.Text = 'Z-CG (ft)';
            app.zcgOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.zcgOutEditField.Tooltip = {'Vehicle Z-CG location'};
            app.AlphadegLabel_63 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_63.Text = 'Ixx (sl-ft2)';
            app.ixxOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.ixxOutEditField.Tooltip = {'Principle moment of inertia about the x-axis in the body frame'};
            app.AlphadegLabel_64 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_64.Text = 'Iyy (sl-ft2)';
            app.iyyOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.iyyOutEditField.Tooltip = {'Principle moment of inertia about the y-axis in the body frame'};
            app.AlphadegLabel_65 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_65.Text = 'Izz (sl-ft2)';
            app.izzOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.izzOutEditField.Tooltip = {'Principle moment of inertia about the z-axis in the body frame'};
            app.AlphadegLabel_66 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_66.Text = 'Ixz (sl-ft2)';
            app.ixzOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.ixzOutEditField.Tooltip = {'Product moment of inertia '};
            app.AlphadegLabel_67 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_67.Text = 'Ixy (sl-ft2)';
            app.ixyOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.ixyOutEditField.Tooltip = {'Product moment of inertia '};
            app.AlphadegLabel_68 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_68.Text = 'Iyz (sl-ft2)';
            app.iyzOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.iyzOutEditField.Tooltip = {'Product moment of inertia '};
            app.AlphadegLabel_74 = uilabel(app.MassPropertiesPanel);
            app.AlphadegLabel_74.Text = 'Mass (sl)';
            app.massOutEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.massOutEditField.Tooltip = {'Vehicle mass'};
            app.EnvironmentPanel = uipanel(app.TrimOutputsTab);
            app.EnvironmentPanel.Title = 'Environment';
            app.AlphadegLabel_69 = uilabel(app.EnvironmentPanel);
            app.AlphadegLabel_69.Text = 'Temp (R)';
            app.tempOutEditField = uieditfield(app.EnvironmentPanel, 'numeric');
            app.tempOutEditField.Tooltip = {'Air temperature'};
            app.AlphadegLabel_70 = uilabel(app.EnvironmentPanel);
            app.AlphadegLabel_70.Text = 'Pair (psi)';
            app.PairOutEditField = uieditfield(app.EnvironmentPanel, 'numeric');
            app.PairOutEditField.Tooltip = {'Air pressure'};
            app.AlphadegLabel_72 = uilabel(app.EnvironmentPanel);
            app.AlphadegLabel_72.Text = 'SoS (fps)';
            app.SoSOutEditField = uieditfield(app.EnvironmentPanel, 'numeric');
            app.SoSOutEditField.Tooltip = {'Speed of sound'};
            app.AlphadegLabel_73 = uilabel(app.EnvironmentPanel);
            app.AlphadegLabel_73.Text = 'rho (slft3)';
            app.rhoOutEditField = uieditfield(app.EnvironmentPanel, 'numeric');
            app.rhoOutEditField.Tooltip = {'Air density'};
            app.LongitudinalStabilityDerivativesTab = uitab(app.OutputsTabGroup);
            app.LongitudinalStabilityDerivativesTab.Title = 'Longitudinal Stability Derivatives';
            app.NonDimLonStabDerivPanel = uipanel(app.LongitudinalStabilityDerivativesTab);
            app.NonDimLonStabDerivPanel.Title = 'Non-Dimensional Longitudinal Stability Derivatives';
            app.CLAlpha1radEditFieldLabel = uilabel(app.NonDimLonStabDerivPanel);
            app.CLAlpha1radEditFieldLabel.Text = 'CL-Alpha (1/rad)';
            app.CLAlphaEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CLAlphaEditField.Tooltip = {''};
            app.CDAlpha1radLabel = uilabel(app.NonDimLonStabDerivPanel);
            app.CDAlpha1radLabel.Text = 'CD-Alpha (1/rad)';
            app.CDAlphaEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CDAlphaEditField.Tooltip = {''};
            app.CDAlpha1radLabel_2 = uilabel(app.NonDimLonStabDerivPanel);
            app.CDAlpha1radLabel_2.Text = 'CM-Alpha (1/rad)';
            app.CMAlphaEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CMAlphaEditField.Tooltip = {''};
            app.CLULabel = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel.Text = 'CL-U ';
            app.CLUEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CLUEditField.Tooltip = {''};
            app.CDUEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CDUEditField.Tooltip = {''};
            app.CMUEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CMUEditField.Tooltip = {''};
            app.CLULabel_2 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_2.Text = 'CL-q (1/rad)';
            app.CLqEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CLqEditField.Tooltip = {''};
            app.CDqEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CDqEditField.Tooltip = {''};
            app.CMqEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CMqEditField.Tooltip = {''};
            app.CLULabel_3 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_3.Text = 'CL-Alpdot (rps)';
            app.CLAlpdotEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CLAlpdotEditField.Tooltip = {''};
            app.CDUEditFieldLabel = uilabel(app.NonDimLonStabDerivPanel);
            app.CDUEditFieldLabel.Text = 'CD-U ';
            app.CMUEditFieldLabel = uilabel(app.NonDimLonStabDerivPanel);
            app.CMUEditFieldLabel.Text = 'CM-U ';
            app.CLULabel_4 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_4.Text = 'CD-q (1/rad)';
            app.CLULabel_5 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_5.Text = 'CM-q (1/rad)';
            app.CLULabel_6 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_6.Text = 'CD-Alpdot (rps)';
            app.CDAlpdotEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CDAlpdotEditField.Tooltip = {''};
            app.CLULabel_7 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_7.Text = 'CM-Alpdot (rps)';
            app.CMAlpdotEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CMAlpdotEditField.Tooltip = {''};
            app.CDAlpha1radLabel_3 = uilabel(app.NonDimLonStabDerivPanel);
            app.CDAlpha1radLabel_3.Text = 'CL-dTHR';
            app.CLdTHREditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CLdTHREditField.Tooltip = {''};
            app.CDAlpha1radLabel_4 = uilabel(app.NonDimLonStabDerivPanel);
            app.CDAlpha1radLabel_4.Text = 'CD-dTHR';
            app.CDdTHREditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CDdTHREditField.Tooltip = {''};
            app.CMdTHREditFieldLabel = uilabel(app.NonDimLonStabDerivPanel);
            app.CMdTHREditFieldLabel.Text = 'CM-dTHR';
            app.CMdTHREditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CMdTHREditField.Tooltip = {''};
            app.CLdA1radEditFieldLabel = uilabel(app.NonDimLonStabDerivPanel);
            app.CLdA1radEditFieldLabel.Text = 'CL-dA (1/rad)';
            app.CLdAEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CLdAEditField.Tooltip = {''};
            app.CDdA1radEditFieldLabel = uilabel(app.NonDimLonStabDerivPanel);
            app.CDdA1radEditFieldLabel.Text = 'CD-dA (1/rad)';
            app.CDdAEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CDdAEditField.Tooltip = {''};
            app.CMdALabel = uilabel(app.NonDimLonStabDerivPanel);
            app.CMdALabel.Text = 'CM-dA (1/rad)';
            app.CMdAEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CMdAEditField.Tooltip = {''};
            app.CLULabel_8 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_8.Text = 'CL-dE (1/rad)';
            app.CLdEEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CLdEEditField.Tooltip = {''};
            app.CLULabel_9 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_9.Text = 'CD-dE (1/rad)';
            app.CDdEEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CDdEEditField.Tooltip = {''};
            app.CLULabel_10 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_10.Text = 'CM-dE (1/rad)';
            app.CMdEEditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CMdEEditField.Tooltip = {''};
            app.CLULabel_11 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_11.Text = 'CL-dR (1/rad)';
            app.CLdREditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CLdREditField.Tooltip = {''};
            app.CLULabel_12 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_12.Text = 'CD-dR (1/rad)';
            app.CDdREditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CDdREditField.Tooltip = {''};
            app.CLULabel_13 = uilabel(app.NonDimLonStabDerivPanel);
            app.CLULabel_13.Text = 'CM-dR (1/rad)';
            app.CMdREditField = uieditfield(app.NonDimLonStabDerivPanel, 'numeric');
            app.CMdREditField.Tooltip = {''};
            app.DimensionalLongitudinalStabilityDerivativesPanel = uipanel(app.LongitudinalStabilityDerivativesTab);
            app.DimensionalLongitudinalStabilityDerivativesPanel.Title = 'Dimensional Longitudinal Stability Derivatives';
            app.XAlphafts2Label = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label.Text = 'X-Alpha (ft/s^2)';
            app.XAlphaEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.XAlphaEditField.Tooltip = {''};
            app.XAlphafts2Label_2 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_2.Text = 'Z-Alpha (ft/s^2)';
            app.ZAlphaEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.ZAlphaEditField.Tooltip = {''};
            app.XAlphafts2Label_3 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_3.Text = 'M-Alpha (1/s^2)';
            app.MAlphaEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.MAlphaEditField.Tooltip = {''};
            app.XAlphafts2Label_4 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_4.Text = 'X-U (1/s)';
            app.XUEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.XUEditField.Tooltip = {''};
            app.XAlphafts2Label_5 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_5.Text = 'Z-U (1/s)';
            app.ZUEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.ZUEditField.Tooltip = {''};
            app.XAlphafts2Label_6 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_6.Text = 'M-U (1/ft*s)';
            app.MUEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.MUEditField.Tooltip = {''};
            app.XAlphafts2Label_7 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_7.Text = 'X-q (ft/s)';
            app.XqEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.XqEditField.Tooltip = {''};
            app.XAlphafts2Label_8 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_8.Text = 'Z-q (ft/s)';
            app.ZqEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.ZqEditField.Tooltip = {''};
            app.XAlphafts2Label_9 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_9.Text = 'M-q (1/s)';
            app.MqEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.MqEditField.Tooltip = {''};
            app.XAlphafts2Label_10 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_10.Text = 'X-alpdot (ft/s)';
            app.XalpdotEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.XalpdotEditField.Tooltip = {''};
            app.XAlphafts2Label_11 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_11.Text = 'Z-alpdot (ft/s)';
            app.ZalpdotEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.ZalpdotEditField.Tooltip = {''};
            app.XAlphafts2Label_12 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_12.Text = 'M-alpdot (1/s)';
            app.MalpdotEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.MalpdotEditField.Tooltip = {''};
            app.XAlphafts2Label_13 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_13.Text = 'X-dTHR (ft/s^2)';
            app.XdTHREditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.XdTHREditField.Tooltip = {''};
            app.XAlphafts2Label_14 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_14.Text = 'Z-dTHR (ft/s^2)';
            app.ZdTHREditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.ZdTHREditField.Tooltip = {''};
            app.XAlphafts2Label_15 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_15.Text = 'M-dTHR (1/s^2)';
            app.MdTHREditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.MdTHREditField.Tooltip = {''};
            app.XAlphafts2Label_16 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_16.Text = 'X-dA (ft/s^2)';
            app.XdAEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.XdAEditField.Tooltip = {''};
            app.XAlphafts2Label_17 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_17.Text = 'Z-dA (ft/s^2)';
            app.ZdAEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.ZdAEditField.Tooltip = {''};
            app.XAlphafts2Label_18 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_18.Text = 'M-dA (1/s^2)';
            app.MdAEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.MdAEditField.Tooltip = {''};
            app.XAlphafts2Label_19 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_19.Text = 'X-dE (ft/s^2)';
            app.XdEEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.XdEEditField.Tooltip = {''};
            app.XAlphafts2Label_20 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_20.Text = 'Z-dE (ft/s^2)';
            app.ZdEEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.ZdEEditField.Tooltip = {''};
            app.XAlphafts2Label_21 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_21.Text = 'M-dE (1/s^2)';
            app.MdEEditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.MdEEditField.Tooltip = {''};
            app.XAlphafts2Label_22 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_22.Text = 'X-dR (ft/s^2)';
            app.XdREditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.XdREditField.Tooltip = {''};
            app.XAlphafts2Label_23 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_23.Text = 'Z-dR (ft/s^2)';
            app.ZdREditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.ZdREditField.Tooltip = {''};
            app.XAlphafts2Label_24 = uilabel(app.DimensionalLongitudinalStabilityDerivativesPanel);
            app.XAlphafts2Label_24.Text = 'M-dR (1/s^2)';
            app.MdREditField = uieditfield(app.DimensionalLongitudinalStabilityDerivativesPanel, 'numeric');
            app.MdREditField.Tooltip = {''};
            app.LatDirStabilityDerivativesTab = uitab(app.OutputsTabGroup);
            app.LatDirStabilityDerivativesTab.Title = 'Lat/Dir Stability Derivatives';
            app.NonDimensionalLatDirStabilityDerivativesPanel = uipanel(app.LatDirStabilityDerivativesTab);
            app.NonDimensionalLatDirStabilityDerivativesPanel.Title = 'Non-Dimensional Lat/Dir Stability Derivatives';
            app.CnBeta1radLabel = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel.Text = 'Cn-Beta (1/rad)';
            app.CnBetaEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CnBetaEditField.Tooltip = {''};
            app.CnBeta1radLabel_2 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_2.Text = 'Cr-Beta (1/rad)';
            app.CrBetaEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CrBetaEditField.Tooltip = {''};
            app.CnBeta1radLabel_3 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_3.Text = 'CY-Beta (1/rad)';
            app.CYBetaEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CYBetaEditField.Tooltip = {''};
            app.CnBeta1radLabel_4 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_4.Text = 'Cn-p (1/rad)';
            app.CnpEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CnpEditField.Tooltip = {''};
            app.CnBeta1radLabel_5 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_5.Text = 'Cr-p (1/rad)';
            app.CrpEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CrpEditField.Tooltip = {''};
            app.CnBeta1radLabel_6 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_6.Text = 'CY-p (1/rad)';
            app.CYpEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CYpEditField.Tooltip = {''};
            app.CnBeta1radLabel_7 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_7.Text = 'Cn-r (1/rad)';
            app.CnrEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CnrEditField.Tooltip = {''};
            app.CnBeta1radLabel_8 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_8.Text = 'Cr-r (1/rad)';
            app.CrrEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CrrEditField.Tooltip = {''};
            app.CnBeta1radLabel_9 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_9.Text = 'CY-r (1/rad)';
            app.CYrEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CYrEditField.Tooltip = {''};
            app.CnBeta1radLabel_10 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_10.Text = 'Cn-betadot (rps)';
            app.CnBetadotEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CnBetadotEditField.Tooltip = {''};
            app.CnBeta1radLabel_11 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_11.Text = 'Cr-betadot (rps)';
            app.CrBetadotEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CrBetadotEditField.Tooltip = {''};
            app.CnBeta1radLabel_12 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_12.Text = 'CY-betadot (rps)';
            app.CYBetadotEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CYBetadotEditField.Tooltip = {''};
            app.CnBeta1radLabel_13 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_13.Text = 'Cn-dTHR';
            app.CndTHREditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CndTHREditField.Tooltip = {''};
            app.CrdTHREditFieldLabel = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CrdTHREditFieldLabel.Text = 'Cr-dTHR';
            app.CrdTHREditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CrdTHREditField.Tooltip = {''};
            app.CYdTHREditFieldLabel = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CYdTHREditFieldLabel.Text = 'CY-dTHR';
            app.CYdTHREditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CYdTHREditField.Tooltip = {''};
            app.CndA1radEditFieldLabel = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CndA1radEditFieldLabel.Text = 'Cn-dA (1/rad)';
            app.CndAEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CndAEditField.Tooltip = {''};
            app.CrdA1radLabel = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CrdA1radLabel.Text = 'Cr-dA (1/rad)';
            app.CrdAEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CrdAEditField.Tooltip = {''};
            app.CrdA1radLabel_2 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CrdA1radLabel_2.Text = 'CY-dA (1/rad)';
            app.CYdAEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CYdAEditField.Tooltip = {''};
            app.CndE1radLabel = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CndE1radLabel.Text = 'Cn-dE (1/rad)';
            app.CndEEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CndEEditField.Tooltip = {''};
            app.CndE1radLabel_2 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CndE1radLabel_2.Text = 'Cr-dE (1/rad)';
            app.CrdEEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CrdEEditField.Tooltip = {''};
            app.CndE1radLabel_3 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CndE1radLabel_3.Text = 'CY-dE (1/rad)';
            app.CYdEEditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CYdEEditField.Tooltip = {''};
            app.CndE1radLabel_4 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CndE1radLabel_4.Text = 'Cn-dR (1/rad)';
            app.CndREditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CndREditField.Tooltip = {''};
            app.CndE1radLabel_5 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CndE1radLabel_5.Text = 'Cr-dR (1/rad)';
            app.CrdREditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CrdREditField.Tooltip = {''};
            app.CndE1radLabel_6 = uilabel(app.NonDimensionalLatDirStabilityDerivativesPanel);
            app.CndE1radLabel_6.Text = 'CY-dR (1/rad)';
            app.CYdREditField = uieditfield(app.NonDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.CYdREditField.Tooltip = {''};
            app.DimensionalLatDirStabilityDerivativesPanel = uipanel(app.LatDirStabilityDerivativesTab);
            app.DimensionalLatDirStabilityDerivativesPanel.Title = 'Dimensional Lat/Dir Stability Derivatives';
            app.CnBeta1radLabel_14 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_14.Text = 'N-Beta (1/s^2)';
            app.NBetaEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NBetaEditField.Tooltip = {''};
            app.CnBeta1radLabel_15 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_15.Text = 'R-Beta (1/s^2)';
            app.RBetaEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RBetaEditField.Tooltip = {''};
            app.CnBeta1radLabel_16 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_16.Text = 'Y-Beta (ft/s^2)';
            app.YBetaEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.YBetaEditField.Tooltip = {''};
            app.CnBeta1radLabel_17 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_17.Text = 'N-p (1/s)';
            app.NpEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NpEditField.Tooltip = {''};
            app.CnBeta1radLabel_18 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_18.Text = 'R-p (1/s)';
            app.RpEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RpEditField.Tooltip = {''};
            app.CnBeta1radLabel_19 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_19.Text = 'Y-p (ft/s)';
            app.YpEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.YpEditField.Tooltip = {''};
            app.CnBeta1radLabel_20 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_20.Text = 'N-r (1/s)';
            app.NrEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NrEditField.Tooltip = {''};
            app.CnBeta1radLabel_21 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_21.Text = 'R-r (1/s)';
            app.RrEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RrEditField.Tooltip = {''};
            app.CnBeta1radLabel_22 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_22.Text = 'Y-r (ft/s)';
            app.YrEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.YrEditField.Tooltip = {''};
            app.CnBeta1radLabel_23 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_23.Text = 'N-betadot (1/s)';
            app.NbetadotEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NbetadotEditField.Tooltip = {''};
            app.CnBeta1radLabel_24 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_24.Text = 'R-betadot (1/s)';
            app.RbetadotEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RbetadotEditField.Tooltip = {''};
            app.CnBeta1radLabel_25 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_25.Text = 'Y-betadot (ft/s)';
            app.YbetadotEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.YbetadotEditField.Tooltip = {''};
            app.CnBeta1radLabel_26 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_26.Text = 'N-dTHR (1/s^2)';
            app.NdTHREditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NdTHREditField.Tooltip = {''};
            app.CnBeta1radLabel_27 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_27.Text = 'R-dTHR (1/s^2)';
            app.RdTHREditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RdTHREditField.Tooltip = {''};
            app.CnBeta1radLabel_28 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_28.Text = 'Y-dTHR (ft/s^2)';
            app.YdTHREditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.YdTHREditField.Tooltip = {''};
            app.CnBeta1radLabel_29 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_29.Text = 'N-dA (1/s^2)';
            app.NdAEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NdAEditField.Tooltip = {''};
            app.CnBeta1radLabel_30 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_30.Text = 'R-dA (1/s^2)';
            app.RdAEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RdAEditField.Tooltip = {''};
            app.CnBeta1radLabel_31 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_31.Text = 'Y-dA (ft/s^2)';
            app.YdAEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.YdAEditField.Tooltip = {''};
            app.CnBeta1radLabel_32 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_32.Text = 'N-dE (1/s^2)';
            app.NdEEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NdEEditField.Tooltip = {''};
            app.CnBeta1radLabel_33 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_33.Text = 'R-dE (1/s^2)';
            app.RdEEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RdEEditField.Tooltip = {''};
            app.CnBeta1radLabel_34 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_34.Text = 'Y-dE (ft/s^2)';
            app.YdEEditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.YdEEditField.Tooltip = {''};
            app.CnBeta1radLabel_35 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_35.Text = 'N-dR (1/s^2)';
            app.NdREditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NdREditField.Tooltip = {''};
            app.CnBeta1radLabel_36 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_36.Text = 'R-dR (1/s^2)';
            app.RdREditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RdREditField.Tooltip = {''};
            app.CnBeta1radLabel_37 = uilabel(app.DimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_37.Text = 'Y-dR (ft/s^2)';
            app.YdREditField = uieditfield(app.DimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.YdREditField.Tooltip = {''};
            app.PrimedDimensionalLatDirStabilityDerivativesPanel = uipanel(app.LatDirStabilityDerivativesTab);
            app.PrimedDimensionalLatDirStabilityDerivativesPanel.Title = 'Primed Dimensional Lat/Dir Stability Derivatives';
            app.CnBeta1radLabel_38 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_38.Text = 'N''-Beta (1/s^2)';
            app.NpBetaEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NpBetaEditField.Tooltip = {''};
            app.CnBeta1radLabel_39 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_39.Text = 'R''-Beta (1/s^2)';
            app.RpBetaEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RpBetaEditField.Tooltip = {''};
            app.CnBeta1radLabel_40 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_40.Text = 'N''-p (1/s)';
            app.NppEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NppEditField.Tooltip = {''};
            app.CnBeta1radLabel_41 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_41.Text = 'R''-p (1/s)';
            app.RppEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RppEditField.Tooltip = {''};
            app.CnBeta1radLabel_42 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_42.Text = 'N''-r (1/s)';
            app.NprEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NprEditField.Tooltip = {''};
            app.CnBeta1radLabel_43 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_43.Text = 'R''-r (1/s)';
            app.RprEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RprEditField.Tooltip = {''};
            app.CnBeta1radLabel_44 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_44.Text = 'N''-betadot (1/s)';
            app.NpbdotEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NpbdotEditField.Tooltip = {''};
            app.CnBeta1radLabel_45 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_45.Text = 'R''-betadot (1/s)';
            app.RpbdotEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RpbdotEditField.Tooltip = {''};
            app.CnBeta1radLabel_46 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_46.Text = 'N''-dTHR (1/s^2)';
            app.NpdTHREditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NpdTHREditField.Tooltip = {''};
            app.CnBeta1radLabel_47 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_47.Text = 'R''-dTHR (1/s^2)';
            app.RpdTHREditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RpdTHREditField.Tooltip = {''};
            app.CnBeta1radLabel_48 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_48.Text = 'N''-dA (1/s^2)';
            app.NpdAEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NpdAEditField.Tooltip = {''};
            app.CnBeta1radLabel_49 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_49.Text = 'R''-dA (1/s^2)';
            app.RpdAEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RpdAEditField.Tooltip = {''};
            app.CnBeta1radLabel_50 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_50.Text = 'N''-dE (1/s^2)';
            app.NpdEEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NpdEEditField.Tooltip = {''};
            app.CnBeta1radLabel_51 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_51.Text = 'R''-dE (1/s^2)';
            app.RpdEEditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RpdEEditField.Tooltip = {''};
            app.CnBeta1radLabel_52 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_52.Text = 'N''-dR (1/s^2)';
            app.NpdREditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.NpdREditField.Tooltip = {''};
            app.CnBeta1radLabel_53 = uilabel(app.PrimedDimensionalLatDirStabilityDerivativesPanel);
            app.CnBeta1radLabel_53.Text = 'R''-dR (1/s^2)';
            app.RpdREditField = uieditfield(app.PrimedDimensionalLatDirStabilityDerivativesPanel, 'numeric');
            app.RpdREditField.Tooltip = {''};
            app.LinearSystemTab = uitab(app.OutputsTabGroup);
            app.LinearSystemTab.Title = 'Linear System';
            app.LongitudinalPanel = uipanel(app.LinearSystemTab);
            app.LongitudinalPanel.Title = 'Open Loop Longitudinal Characteristics';
            app.ALabel = uilabel(app.LongitudinalPanel);
            app.ALabel.Text = 'A =';
            app.Alon11EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon11EditField.Tooltip = {''};
            app.Alon12EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon12EditField.Tooltip = {''};
            app.Alon13EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon13EditField.Tooltip = {''};
            app.Alon14EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon14EditField.Tooltip = {''};
            app.Alon21EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon21EditField.Tooltip = {''};
            app.Alon22EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon22EditField.Tooltip = {''};
            app.Alon23EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon23EditField.Tooltip = {''};
            app.Alon24EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon24EditField.Tooltip = {''};
            app.Alon31EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon31EditField.Tooltip = {''};
            app.Alon32EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon32EditField.Tooltip = {''};
            app.Alon33EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon33EditField.Tooltip = {''};
            app.Alon34EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon34EditField.Tooltip = {''};
            app.Alon41EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon41EditField.Tooltip = {''};
            app.Alon42EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon42EditField.Tooltip = {''};
            app.Alon43EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon43EditField.Tooltip = {''};
            app.Alon44EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Alon44EditField.Tooltip = {''};
            app.BLabel = uilabel(app.LongitudinalPanel);
            app.BLabel.Text = 'B =';
            app.Blon11EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon11EditField.Tooltip = {''};
            app.Blon12EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon12EditField.Tooltip = {''};
            app.Blon13EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon13EditField.Tooltip = {''};
            app.Blon14EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon14EditField.Tooltip = {''};
            app.Blon21EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon21EditField.Tooltip = {''};
            app.Blon22EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon22EditField.Tooltip = {''};
            app.Blon23EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon23EditField.Tooltip = {''};
            app.Blon24EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon24EditField.Tooltip = {''};
            app.Blon31EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon31EditField.Tooltip = {''};
            app.Blon32EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon32EditField.Tooltip = {''};
            app.Blon33EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon33EditField.Tooltip = {''};
            app.Blon34EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon34EditField.Tooltip = {''};
            app.Blon41EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon41EditField.Tooltip = {''};
            app.Blon42EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon42EditField.Tooltip = {''};
            app.Blon43EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon43EditField.Tooltip = {''};
            app.Blon44EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.Blon44EditField.Tooltip = {''};
            app.EigenvaluesLabel = uilabel(app.LongitudinalPanel);
            app.EigenvaluesLabel.Text = 'Eigenvalues';
            app.PeriodsLabel = uilabel(app.LongitudinalPanel);
            app.PeriodsLabel.Text = 'Period (s)';
            app.LonZeta1EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.LonZeta1EditField.Tooltip = {''};
            app.LonZeta2EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.LonZeta2EditField.Tooltip = {''};
            app.LonZeta3EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.LonZeta3EditField.Tooltip = {''};
            app.LonZeta4EditField = uieditfield(app.LongitudinalPanel, 'numeric');
            app.LonZeta4EditField.Tooltip = {''};
            app.DampingLabel = uilabel(app.LongitudinalPanel);
            app.DampingLabel.Text = 'Damping';
            app.TimeToDoubleLabel = uilabel(app.LongitudinalPanel);
            app.TimeToDoubleLabel.Text = 'Time To Double';
            app.LonEig1EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonEig1EditField.Value = '0';
            app.LonControl = uiimage(app.LongitudinalPanel);
            app.LonControl.ImageSource = 'control.png';
            app.LonStates = uiimage(app.LongitudinalPanel);
            app.LonStates.ImageSource = 'lon_States.png';
            app.LonEig2EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonEig2EditField.Value = '0';
            app.LonEig3EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonEig3EditField.Value = '0';
            app.LonEig4EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonEig4EditField.Value = '0';
            app.LonTTD1EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonTTD1EditField.Value = '0';
            app.LonTTD2EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonTTD2EditField.Value = '0';
            app.LonTTD3EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonTTD3EditField.Value = '0';
            app.LonTTD4EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonTTD4EditField.Value = '0';
            app.TauLabel = uilabel(app.LongitudinalPanel);
            app.TauLabel.Text = 'Tau';
            app.LonT1EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonT1EditField.Value = '0';
            app.LonT2EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonT2EditField.Value = '0';
            app.LonT3EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonT3EditField.Value = '0';
            app.LonT4EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonT4EditField.Value = '0';
            app.LonTau1EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonTau1EditField.Value = '0';
            app.LonTau2EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonTau2EditField.Value = '0';
            app.LonTau3EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonTau3EditField.Value = '0';
            app.LonTau4EditField = uieditfield(app.LongitudinalPanel, 'text');
            app.LonTau4EditField.Value = '0';
            app.LatDirPanel = uipanel(app.LinearSystemTab);
            app.LatDirPanel.Title = 'Open Loop Lateral/Directional Characteristics';
            app.ALatLabel = uilabel(app.LatDirPanel);
            app.ALatLabel.Text = 'A =';
            app.Alat11EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat11EditField.Tooltip = {''};
            app.Alat12EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat12EditField.Tooltip = {''};
            app.Alat13EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat13EditField.Tooltip = {''};
            app.Alat14EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat14EditField.Tooltip = {''};
            app.Alat21EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat21EditField.Tooltip = {''};
            app.Alat22EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat22EditField.Tooltip = {''};
            app.Alat23EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat23EditField.Tooltip = {''};
            app.Alat24EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat24EditField.Tooltip = {''};
            app.Alat31EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat31EditField.Tooltip = {''};
            app.Alat32EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat32EditField.Tooltip = {''};
            app.Alat33EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat33EditField.Tooltip = {''};
            app.Alat34EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat34EditField.Tooltip = {''};
            app.Alat41EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat41EditField.Tooltip = {''};
            app.Alat42EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat42EditField.Tooltip = {''};
            app.Alat43EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat43EditField.Tooltip = {''};
            app.Alat44EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Alat44EditField.Tooltip = {''};
            app.BlatLabel = uilabel(app.LatDirPanel);
            app.BlatLabel.Text = 'B =';
            app.Blat11EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat11EditField.Tooltip = {''};
            app.Blat12EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat12EditField.Tooltip = {''};
            app.Blat13EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat13EditField.Tooltip = {''};
            app.Blat14EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat14EditField.Tooltip = {''};
            app.Blat21EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat21EditField.Tooltip = {''};
            app.Blat22EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat22EditField.Tooltip = {''};
            app.Blat23EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat23EditField.Tooltip = {''};
            app.Blat24EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat24EditField.Tooltip = {''};
            app.Blat31EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat31EditField.Tooltip = {''};
            app.Blat32EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat32EditField.Tooltip = {''};
            app.Blat33EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat33EditField.Tooltip = {''};
            app.Blat34EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat34EditField.Tooltip = {''};
            app.Blat41EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat41EditField.Tooltip = {''};
            app.Blat42EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat42EditField.Tooltip = {''};
            app.Blat43EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat43EditField.Tooltip = {''};
            app.Blat44EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.Blat44EditField.Tooltip = {''};
            app.LatEigenvaluesLabel = uilabel(app.LatDirPanel);
            app.LatEigenvaluesLabel.Text = 'Eigenvalues';
            app.LatNatFreqLabel = uilabel(app.LatDirPanel);
            app.LatNatFreqLabel.Text = 'Period (s)';
            app.LatZeta1EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.LatZeta1EditField.Tooltip = {''};
            app.LatZeta2EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.LatZeta2EditField.Tooltip = {''};
            app.LatZeta3EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.LatZeta3EditField.Tooltip = {''};
            app.LatZeta4EditField = uieditfield(app.LatDirPanel, 'numeric');
            app.LatZeta4EditField.Tooltip = {''};
            app.LatDampingLabel = uilabel(app.LatDirPanel);
            app.LatDampingLabel.Text = 'Damping';
            app.LatTimeToDoubleLabel = uilabel(app.LatDirPanel);
            app.LatTimeToDoubleLabel.Text = 'Time To Double';
            app.LatStates = uiimage(app.LatDirPanel);
            app.LatStates.ImageSource = 'lat_States.png';
            app.LatControl = uiimage(app.LatDirPanel);
            app.LatControl.ImageSource = 'control.png';
            app.LatEig1EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatEig1EditField.Value = '0';
            app.LatEig2EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatEig2EditField.Value = '0';
            app.LatEig3EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatEig3EditField.Value = '0';
            app.LatEig4EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatEig4EditField.Value = '0';
            app.LatTTD1EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatTTD1EditField.Value = '0';
            app.LatTTD2EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatTTD2EditField.Value = '0';
            app.LatTTD3EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatTTD3EditField.Value = '0';
            app.LatTTD4EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatTTD4EditField.Value = '0';
            app.LatNatFreqLabel_2 = uilabel(app.LatDirPanel);
            app.LatNatFreqLabel_2.Text = 'Tau';
            app.LatT1EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatT1EditField.Value = '0';
            app.LatT2EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatT2EditField.Value = '0';
            app.LatT3EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatT3EditField.Value = '0';
            app.LatT4EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatT4EditField.Value = '0';
            app.LatTau1EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatTau1EditField.Value = '0';
            app.LatTau2EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatTau2EditField.Value = '0';
            app.LatTau3EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatTau3EditField.Value = '0';
            app.LatTau4EditField = uieditfield(app.LatDirPanel, 'text');
            app.LatTau4EditField.Value = '0';
            app.TrimVehicleButton = uibutton(app.SingleTrimTab, 'push');
            app.TrimVehicleButton.ButtonPushedFcn = createCallbackFcn(app, @TrimVehicleButtonPushed, true);
            app.TrimVehicleButton.Tooltip = {'Trim vehicle based on desired selections'};
            app.TrimVehicleButton.Text = 'Trim Vehicle';
            app.SuccessfulTrimLabel = uilabel(app.SingleTrimTab);
            app.SuccessfulTrimLabel.Text = {'Successful '; 'Trim'};
            app.SuccessfulTrimLamp = uilamp(app.SingleTrimTab);
            app.SuccessfulTrimLamp.Tooltip = {'If green, the trim was successful. If red, the trim was unsuccessful'};
            app.LoadFCButton = uibutton(app.SingleTrimTab, 'push');
            app.LoadFCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFCButtonPushed, true);
            app.LoadFCButton.Tooltip = {'Load flight conditions (trim inputs) based on previously computed trim solution (.mat file)'};
            app.LoadFCButton.Text = 'Load FC';
            app.LoadTrimModelButton = uibutton(app.SingleTrimTab, 'push');
            app.LoadTrimModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrimModelButtonPushed, true);
            app.LoadTrimModelButton.Tooltip = {'Select trim model to be used for trim routine. This must be loaded before being able to run the trim tool'};
            app.LoadTrimModelButton.Text = 'Load Trim Model';
            app.SaveTrimStateButton = uibutton(app.SingleTrimTab, 'push');
            app.SaveTrimStateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTrimStateButtonPushed, true);
            app.SaveTrimStateButton.Tooltip = {'Save trim outputs to a .mat file'};
            app.SaveTrimStateButton.Text = 'Save Trim State';
            app.StaticStabilityandControlTab = uitab(app.TabGroup);
            app.StaticStabilityandControlTab.Title = 'Static Stability and Control';
            app.LinearAnalysisTab = uitab(app.TabGroup);
            app.LinearAnalysisTab.Title = 'Linear Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRIMLAT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end